classdef DatasetManagerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DatasetDropdown        matlab.ui.control.DropDown
        DownloadButton         matlab.ui.control.Button
        ResizeDropdown         matlab.ui.control.DropDown
        NormalizationDropdown  matlab.ui.control.DropDown
        DatasetStatsText       matlab.ui.control.TextArea
        SampleImageAxes        matlab.ui.control.UIAxes
        ClassDistributionAxes  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        choosenDataset         string  % here will be path to downloaded dataset
        ParentApp
    end
    methods (Access = private)
        
        function updateDatasetStats(app)
            % Update dataset statistics based on selected dataset
            selectedDataset = app.DatasetDropdown.Value;
            switch selectedDataset
                case 'MNIST'
                    stats = struct('NumClasses', 10, 'NumImages', 70000, 'Dimensions', '28x28');
                    classDistribution = rand(1, 10) * 1000; % Random distribution for example
                case 'Fashion-MNIST'
                    stats = struct('NumClasses', 10, 'NumImages', 70000, 'Dimensions', '28x28');
                    classDistribution = rand(1, 10) * 1000;
                case 'CIFAR-10'
                    stats = struct('NumClasses', 10, 'NumImages', 60000, 'Dimensions', '32x32x3');
                    classDistribution = rand(1, 10) * 1000;
                case 'CIFAR-100'
                    stats = struct('NumClasses', 100, 'NumImages', 60000, 'Dimensions', '32x32x3');
                    classDistribution = rand(1, 100) * 100;
                case 'ImageNet'
                    stats = struct('NumClasses', 1000, 'NumImages', 14000000, 'Dimensions', 'Varies');
                    classDistribution = rand(1, 1000) * 10;
                case 'Street Scene'
                    stats = struct('NumClasses', 20, 'NumImages', 25000, 'Dimensions', 'Varies');
                    classDistribution = rand(1, 20) * 100;
                case 'Pascal VOC'
                    stats = struct('NumClasses', 20, 'NumImages', 11530, 'Dimensions', 'Varies');
                    classDistribution = rand(1, 20) * 100;
                case 'ECG'
                    stats = struct('NumClasses', 5, 'NumImages', 10000, 'Dimensions', '1D Signal');
                    classDistribution = rand(1, 5) * 1000;
                otherwise
                    stats = struct('NumClasses', 0, 'NumImages', 0, 'Dimensions', 'N/A');
                    classDistribution = [];
            end
            app.DatasetStatsText.Value = {...
                sprintf('Dataset: %s', selectedDataset), ...
                sprintf('Broj klasa: %d', stats.NumClasses), ...
                sprintf('Broj slika: %d', stats.NumImages), ...
                sprintf('Dimenzije: %s', stats.Dimensions)};
            
            % Display a sample image (if applicable)
            displaySampleImage(app, selectedDataset);
            
            % Plot class distribution
            plotClassDistribution(app, classDistribution);
        end
        
        function displaySampleImage(app, datasetName)
            % Display a sample image from the selected dataset
            cla(app.SampleImageAxes); % Clear the axes
            try
                switch datasetName
                    case 'MNIST'
                        img = imread('+SampleImages\mnist_sample.png'); % Replace with actual path
                    case 'Fashion-MNIST'
                        img = imread('+SampleImages\fashion_mnist_sample.png'); % Replace with actual path
                    case 'CIFAR-10'
                        img = imread('+SampleImages\cifar10_sample.jpg'); % Replace with actual path
                    case 'CIFAR-100'
                        img = imread('+SampleImages\cifar100_sample.jpg'); % Replace with actual path
                    case 'ImageNet'
                        img = imread('+SampleImages\imagenet_sample.jpg'); % Replace with actual path
                    case 'Street Scene'
                        img = imread('+SampleImages\street_scene_sample.jpg'); % Replace with actual path
                    case 'Pascal VOC'
                        img = imread('+SampleImages\pascal_voc_sample.jpg'); % Replace with actual path
                    case 'ECG'
                        % For ECG, plot a sample signal instead of an image
                        t = linspace(0, 1, 1000);
                        signal = sin(2 * pi * 5 * t) + 0.5 * randn(1, 1000);
                        plot(app.SampleImageAxes, t, signal);
                        xlabel(app.SampleImageAxes, 'Vrijeme');
                        ylabel(app.SampleImageAxes, 'Amplituda');
                        title(app.SampleImageAxes, 'ECG Signal');
                        return;
                    otherwise
                        img = [];
                end
                if ~isempty(img)
                    % Apply normalization and resizing
                    img = applyNormalizationToImage(app, img);
                    img = applyResizingToImage(app, img);
                    imshow(img, 'Parent', app.SampleImageAxes);
                    title(app.SampleImageAxes, sprintf('Primjer slike: %s', datasetName));
                end
            catch
                text(app.SampleImageAxes, 0.5, 0.5, 'Nema dostupnih slika', ...
                    'HorizontalAlignment', 'center', 'FontSize', 14);
            end
        end
        
        function plotClassDistribution(app, classDistribution)
            % Plot class distribution as a bar chart
            cla(app.ClassDistributionAxes); % Clear the axes
            if ~isempty(classDistribution)
                bar(app.ClassDistributionAxes, classDistribution);
                xlabel(app.ClassDistributionAxes, 'Klase');
                ylabel(app.ClassDistributionAxes, 'Broj slika');
                title(app.ClassDistributionAxes, 'Distribucija klasa');
            else
                text(app.ClassDistributionAxes, 0.5, 0.5, 'Nema dostupnih podataka', ...
                    'HorizontalAlignment', 'center', 'FontSize', 14);
            end
        end
        
        function downloadDataset(app)
    selectedDataset = app.DatasetDropdown.Value;
    
    % Define the base directory where datasets will be stored
    baseDatasetDir = fullfile(pwd, 'datasets'); % Create a "datasets" folder in the current directory
    if ~exist(baseDatasetDir, 'dir')
        mkdir(baseDatasetDir); % Create the directory if it doesn't exist
    end
    
    % Define dataset-specific paths
    switch selectedDataset
        case 'MNIST'
            datasetSubDir = 'MNIST';
            % Verified working URL for MNIST (single archive)
            url = 'https://github.com/myleott/mnist_png/raw/master/mnist_png.tar.gz';
            filename = 'mnist_png.tar.gz';
            
        case 'Fashion-MNIST'
            datasetSubDir = 'FashionMNIST';
            % Verified working URL for Fashion-MNIST (single archive)
            url = 'https://github.com/zalandoresearch/fashion-mnist/archive/refs/heads/master.zip';
            filename = 'fashion-mnist.zip';
            
        case 'CIFAR-10'
            datasetSubDir = 'CIFAR10';
            % Verified working URL for CIFAR-10
            url = 'https://www.cs.toronto.edu/~kriz/cifar-10-matlab.tar.gz';
            filename = 'cifar10.tar.gz';
            
        case 'CIFAR-100'
            datasetSubDir = 'CIFAR100';
            % Verified working URL for CIFAR-100
            url = 'https://www.cs.toronto.edu/~kriz/cifar-100-matlab.tar.gz';
            filename = 'cifar100.tar.gz';
            
        case 'ImageNet'
            datasetSubDir = 'ImageNet';
            % Verified working URL for ImageNet (requires registration, but this is the official link)
            url = 'https://image-net.org/data/ILSVRC/2012/ILSVRC2012_img_train.tar';
            filename = 'imagenet_train.tar';
            
        case 'Pascal VOC'
            datasetSubDir = 'PascalVOC';
            % Verified working URL for Pascal VOC
            url = 'http://host.robots.ox.ac.uk/pascal/VOC/voc2012/VOCtrainval_11-May-2012.tar';
            filename = 'pascal_voc.tar';
            
        otherwise
            disp('Dataset nije podržan.');
            return;
    end
    
    % Set the dataset path IMMEDIATELY (even if not downloaded yet)
    app.choosenDataset = fullfile(baseDatasetDir, datasetSubDir); % <-- Critical for other pages
    disp(['Dataset path set to: ', app.choosenDataset]);
    
    % Check if dataset already exists
    if exist(app.choosenDataset, 'dir')
        disp('Dataset već postoji. Preskačem preuzimanje.');
        uiresume(app.UIFigure); % Notify the app to proceed
        return;
    end
    
    % Create dataset directory if it doesn't exist
    if ~exist(app.choosenDataset, 'dir')
        mkdir(app.choosenDataset);
    end
    
    % Download dataset (with progress bar)
    h = waitbar(0, 'Preuzimanje u toku...', 'Name', 'Preuzimanje dataseta');
    try
        % Download the dataset
        downloadedFile = fullfile(app.choosenDataset, filename);
        websave(downloadedFile, url, ...
            'ProgressFcn', @(msg) downloadProgressCallback(msg, h));
        
        % Extract files (if needed)
        switch selectedDataset
            case {'MNIST', 'Fashion-MNIST', 'CIFAR-10', 'CIFAR-100', 'ImageNet', 'Pascal VOC'}
                untar(downloadedFile, app.choosenDataset); % Extract .tar.gz or .tar
                delete(downloadedFile); % Remove the archive after extraction
        end
        
        waitbar(1, h, 'Preuzimanje završeno!');
        pause(1);
        close(h);
        uiresume(app.UIFigure); % Notify the app to proceed
        disp('Dataset uspešno preuzet i ekstrahovan!');
        
    catch ME
        close(h);
        disp('Greška tokom preuzimanja:');
        disp(ME.message);
        rmdir(app.choosenDataset, 's'); % Clean up empty directory on failure
    end
end
    function resizeImages(app)
            % Resize images based on the selected resize option
            selectedDataset = app.DatasetDropdown.Value;
            switch selectedDataset
                case {'MNIST', 'Fashion-MNIST', 'CIFAR-10', 'CIFAR-100', 'ImageNet', 'Street Scene', 'Pascal VOC'}
                    % Resize the sample image
                    img = getimage(app.SampleImageAxes);
                    if ~isempty(img)
                        img = applyResizingToImage(app, img);
                        imshow(img, 'Parent', app.SampleImageAxes);
                        title(app.SampleImageAxes, sprintf('Resized Image: %s', selectedDataset));
                    end
                case 'ECG'
                    % For ECG, resizing is not applicable
                    disp('Resizing not applicable for ECG dataset.');
                otherwise
                    disp('Dataset nije podržan za resizing.');
            end
        end
        
        function applyNormalization(app)
            % Apply the selected normalization method
            selectedDataset = app.DatasetDropdown.Value;
            switch selectedDataset
                case {'MNIST', 'Fashion-MNIST', 'CIFAR-10', 'CIFAR-100', 'ImageNet', 'Street Scene', 'Pascal VOC'}
                    % Update the sample image with normalization
                    displaySampleImage(app, selectedDataset);
                case 'ECG'
                    % For ECG, normalization is not applicable
                    disp('Normalization not applicable for ECG dataset.');
                otherwise
                    disp('Dataset nije podržan za normalizaciju.');
            end
        end
        
        function img = applyNormalizationToImage(app, img)
            % Apply the selected normalization method to the image
            normalizationMethod = app.NormalizationDropdown.Value;
            switch normalizationMethod
                case 'MinMax'
                    % Min-Max normalization
                    img = double(img);
                    img = (img - min(img(:))) / (max(img(:)) - min(img(:)));
                case 'Mean-Std'
                    % Mean-Std normalization
                    img = double(img);
                    img = (img - mean(img(:))) / std(img(:));
            end
        end
        
        function img = applyResizingToImage(app, img)
            % Apply the selected resize option to the image
            resizeOption = app.ResizeDropdown.Value;
            switch resizeOption
                case '224x224'
                    img = imresize(img, [224, 224]);
                case '64x64'
                    img = imresize(img, [64, 64]);
                case 'Custom'
                    % Allow user to input custom dimensions
                    prompt = {'Enter width:', 'Enter height:'};
                    dlgtitle = 'Custom Resize';
                    dims = [1 35];
                    answer = inputdlg(prompt, dlgtitle, dims);
                    if ~isempty(answer)
                        width = str2double(answer{1});
                        height = str2double(answer{2});
                        img = imresize(img, [height, width]);
                    end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize dataset statistics
            updateDatasetStats(app);
            %uiwait(app.UIFigure);
        end

        % Value changed function: DatasetDropdown
        function DatasetDropdownValueChanged(app, event)
            updateDatasetStats(app);
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            downloadDataset(app);
        end

        % Value changed function: ResizeDropdown
        function ResizeDropdownValueChanged(app, event)
            resizeImages(app);
        end

        % Value changed function: NormalizationDropdown
        function NormalizationDropdownValueChanged(app, event)
            applyNormalization(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.UIFigure);  
            %delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Dataset Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ClassDistributionAxes = uiaxes(app.UIFigure);
            app.SampleImageAxes = uiaxes(app.UIFigure);
            app.DatasetStatsText = uitextarea(app.UIFigure);
            app.DatasetStatsText.Editable = 'off';
            app.NormalizationDropdown = uidropdown(app.UIFigure);
            app.NormalizationDropdown.Items = {'MinMax', 'Mean-Std'};
            app.NormalizationDropdown.ValueChangedFcn = createCallbackFcn(app, @NormalizationDropdownValueChanged, true);
            app.NormalizationDropdown.Value = 'MinMax';
            app.ResizeDropdown = uidropdown(app.UIFigure);
            app.ResizeDropdown.Items = {'224x224', '64x64', 'Custom'};
            app.ResizeDropdown.ValueChangedFcn = createCallbackFcn(app, @ResizeDropdownValueChanged, true);
            app.ResizeDropdown.Value = '224x224';
            app.DownloadButton = uibutton(app.UIFigure, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.DatasetDropdown = uidropdown(app.UIFigure);
            app.DatasetDropdown.Items = {'MNIST', 'Fashion-MNIST', 'CIFAR-10', 'CIFAR-100', 'ImageNet', 'Street Scene', 'Pascal VOC', 'ECG'};
            app.DatasetDropdown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropdownValueChanged, true);
            app.DatasetDropdown.Value = 'MNIST';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetManagerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end