classdef TrainingCNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProgressBar                 matlab.ui.control.LinearGauge
        GaugeLabel                  matlab.ui.control.Label
        TrainingParamsPanel         matlab.ui.container.Panel
        StartTrainingButton         matlab.ui.control.Button
        PlotsDropDown               matlab.ui.control.DropDown
        PlotsLabel                  matlab.ui.control.Label
        ShuffleDropDown             matlab.ui.control.DropDown
        ShuffleLabel                matlab.ui.control.Label
        ValidationFrequencySpinner  matlab.ui.control.Spinner
        ValidationFrequencyLabel    matlab.ui.control.Label
        GradientThresholdEditField  matlab.ui.control.NumericEditField
        GradientThresholdLabel      matlab.ui.control.Label
        WeightDecayEditField        matlab.ui.control.NumericEditField
        WeightDecayLabel            matlab.ui.control.Label
        MomentumEditField           matlab.ui.control.NumericEditField
        MomentumLabel               matlab.ui.control.Label
        OptimizerDropDown           matlab.ui.control.DropDown
        OptimizerLabel              matlab.ui.control.Label
        EpochsSpinner               matlab.ui.control.Spinner
        EpochsLabel                 matlab.ui.control.Label
        BatchSizeEditField          matlab.ui.control.NumericEditField
        BatchSizeLabel              matlab.ui.control.Label
        LearningRateEditField       matlab.ui.control.NumericEditField
        LearningRateLabel           matlab.ui.control.Label
        TrainingPlot                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        DatasetPath
        Architecture
    end

    methods (Access = public)
        
        function results = loadData(app, architecture, dataset)
            app.Architecture = architecture;
            app.DatasetPath = dataset;
        end

        function plotTrainingMetrics(trainedNet, app)
            % Example plotting function
            trainingInfo = trainedNet.TrainingHistory;
            plot(app.TrainingPlot, ...
                trainingInfo.Epoch, trainingInfo.TrainingAccuracy, '-o', ...
                trainingInfo.Epoch, trainingInfo.ValidationAccuracy, '-x');
            legend(app.TrainingPlot, 'Training Accuracy', 'Validation Accuracy');
            xlabel(app.TrainingPlot, 'Epoch');
            ylabel(app.TrainingPlot, 'Accuracy');
            title(app.TrainingPlot, 'Training Progress');
        end
 function [images, labels] = loadImagesAndLabels(app, folderPath)
        subDirs = dir(folderPath);
        subDirs = subDirs([subDirs.isdir]);
        subDirs = subDirs(~ismember({subDirs.name}, {'.', '..'}));
    
        images = {}; % Cell array of character vectors
        labels = [];
    
        for i = 1:length(subDirs)
            classDir = fullfile(folderPath, subDirs(i).name);
            imageFiles = dir(fullfile(classDir, '*.png'));
            
            for j = 1:length(imageFiles)
                imgPath = fullfile(classDir, imageFiles(j).name);
                images{end+1} = char(imgPath); % Explicitly convert to character vector
                labels = [labels; str2double(subDirs(i).name)];
            end
        end
end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
app.StartTrainingButton.Enable = 'off';
    app.ProgressBar.Value = 0;
    cla(app.TrainingPlot);
    
    try
        % =====================================================================
        % 1. Validate Dataset Path and Structure
        % =====================================================================
        if isempty(app.DatasetPath) || ~exist(app.DatasetPath, 'dir')
            error('Dataset path not set or invalid: %s', app.DatasetPath);
        end
        
        % Verify MNIST directory structure
        requiredPaths = {
            fullfile(app.DatasetPath, 'mnist_png', 'training'),...
            fullfile(app.DatasetPath, 'mnist_png', 'testing')
        };
        
        for i = 1:numel(requiredPaths)
            if ~exist(requiredPaths{i}, 'dir')
                error('Missing directory: %s', requiredPaths{i});
            end
        end

        % =====================================================================
        % 2. Load and Prepare Data
        % =====================================================================
        % Load training data
        [trainImages, trainLabels] = loadImagesAndLabels(app, fullfile(app.DatasetPath, 'mnist_png', 'training'));
        
        % Load validation data
        [valImages, valLabels] = loadImagesAndLabels(app, fullfile(app.DatasetPath, 'mnist_png', 'testing'));

        % Ensure trainImages and valImages are cell arrays of character vectors
        if iscell(trainImages) && ~isempty(trainImages) && ischar(trainImages{1})
            % Already in correct format
        else
            error('trainImages is not a cell array of character vectors.');
        end

        % Convert to image datastores
        imdsTrain = imageDatastore(trainImages, 'Labels', categorical(trainLabels));
        imdsVal = imageDatastore(valImages, 'Labels', categorical(valLabels));

        % =====================================================================
        % 3. Define CNN Architecture
        % =====================================================================
        layers = [
            imageInputLayer([28 28 1], 'Name', 'input')
            
            convolution2dLayer(3, 32, 'Padding', 'same', 'Name', 'conv1')
            batchNormalizationLayer('Name', 'bn1')
            reluLayer('Name', 'relu1')
            
            maxPooling2dLayer(2, 'Stride', 2, 'Name', 'pool1')
            
            convolution2dLayer(3, 64, 'Padding', 'same', 'Name', 'conv2')
            batchNormalizationLayer('Name', 'bn2')
            reluLayer('Name', 'relu2')
            
            fullyConnectedLayer(10, 'Name', 'fc')
            softmaxLayer('Name', 'softmax')
            classificationLayer('Name', 'output')
        ];

        % =====================================================================
        % 4. Configure Training Options
        % =====================================================================
        options = trainingOptions(...
            app.OptimizerDropDown.Value, ...
            'Plots', app.PlotsDropDown.Value, ...
            'Shuffle', app.ShuffleDropDown.Value, ...
            'MaxEpochs', app.EpochsSpinner.Value, ...
            'MiniBatchSize', app.BatchSizeEditField.Value, ...
            'InitialLearnRate', app.LearningRateEditField.Value, ...
            'Momentum', app.MomentumEditField.Value, ...
            'L2Regularization', app.WeightDecayEditField.Value, ...
            'GradientThreshold', app.GradientThresholdEditField.Value, ...
            'ValidationData', imdsVal, ...
            'ValidationFrequency', app.ValidationFrequencySpinner.Value, ...
            'OutputFcn', @(info) trainingProgressCallback(app, info));

        % =====================================================================
        % 5. Train the Network
        % =====================================================================
        [trainedNet, trainInfo] = trainNetwork(imdsTrain, layers, options);

        % =====================================================================
        % 6. Display Results
        % =====================================================================
        plotTrainingMetrics(trainedNet, app);
        app.ProgressBar.Value = 100;

    catch ME
        % Error handling
        errMsg = sprintf('ERROR: %s\nFile: %s\nLine: %d',...
            ME.message, ME.stack(1).file, ME.stack(1).line);
        uialert(app.UIFigure, errMsg, 'Error', 'Icon', 'error');
        fprintf(2, '=== DEBUG ===\n%s\n', getReport(ME));
    end
    
    app.StartTrainingButton.Enable = 'on';
    
    % Nested function for progress updates
    function stop = trainingProgressCallback(app, info)
        stop = false;
        if ~isempty(info.ValidationLoss)
            progress = 100 * info.Epoch / app.EpochsSpinner.Value;
            app.ProgressBar.Value = progress;
        end
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrainingPlot = uiaxes(app.UIFigure);
            title(app.TrainingPlot, 'Training Progress')
            xlabel(app.TrainingPlot, 'Epoch')
            ylabel(app.TrainingPlot, 'Metric')
            zlabel(app.TrainingPlot, 'Z')
            app.TrainingParamsPanel = uipanel(app.UIFigure);
            app.TrainingParamsPanel.Title = 'Training Parameters';
            app.LearningRateLabel = uilabel(app.TrainingParamsPanel);
            app.LearningRateLabel.Text = 'Learning Rate:';
            app.LearningRateEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.LearningRateEditField.Limits = [0.0001 0.01];
            app.LearningRateEditField.Value = 0.001;
            app.BatchSizeLabel = uilabel(app.TrainingParamsPanel);
            app.BatchSizeLabel.Text = 'Batch Size:';
            app.BatchSizeEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.BatchSizeEditField.Limits = [16 256];
            app.BatchSizeEditField.Value = 32;
            app.EpochsLabel = uilabel(app.TrainingParamsPanel);
            app.EpochsLabel.Text = 'Epochs:';
            app.EpochsSpinner = uispinner(app.TrainingParamsPanel);
            app.EpochsSpinner.Limits = [5 100];
            app.EpochsSpinner.Value = 5;
            app.OptimizerLabel = uilabel(app.TrainingParamsPanel);
            app.OptimizerLabel.Text = 'Optimizer:';
            app.OptimizerDropDown = uidropdown(app.TrainingParamsPanel);
            app.OptimizerDropDown.Items = {'sgdm', 'adam', 'rmsprop'};
            app.OptimizerDropDown.Value = 'sgdm';
            app.MomentumLabel = uilabel(app.TrainingParamsPanel);
            app.MomentumLabel.Text = 'Momentum:';
            app.MomentumEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.MomentumEditField.Limits = [0.8 0.99];
            app.MomentumEditField.Value = 0.8;
            app.WeightDecayLabel = uilabel(app.TrainingParamsPanel);
            app.WeightDecayLabel.Text = 'Weight Decay:';
            app.WeightDecayEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.WeightDecayEditField.Limits = [0.0001 0.001];
            app.WeightDecayEditField.Value = 0.0005;
            app.GradientThresholdLabel = uilabel(app.TrainingParamsPanel);
            app.GradientThresholdLabel.Text = 'Gradient Threshold:';
            app.GradientThresholdEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.GradientThresholdEditField.Limits = [1 5];
            app.GradientThresholdEditField.Value = 1;
            app.ValidationFrequencyLabel = uilabel(app.TrainingParamsPanel);
            app.ValidationFrequencyLabel.Text = 'Validation Frequency:';
            app.ValidationFrequencySpinner = uispinner(app.TrainingParamsPanel);
            app.ValidationFrequencySpinner.Limits = [10 1000];
            app.ValidationFrequencySpinner.Value = 10;
            app.ShuffleLabel = uilabel(app.TrainingParamsPanel);
            app.ShuffleLabel.Text = 'Shuffle:';
            app.ShuffleDropDown = uidropdown(app.TrainingParamsPanel);
            app.ShuffleDropDown.Items = {'every-epoch', 'once', 'never'};
            app.ShuffleDropDown.Value = 'every-epoch';
            app.PlotsLabel = uilabel(app.TrainingParamsPanel);
            app.PlotsLabel.Text = 'Plots:';
            app.PlotsDropDown = uidropdown(app.TrainingParamsPanel);
            app.PlotsDropDown.Items = {'training-progress', 'none'};
            app.PlotsDropDown.Value = 'training-progress';
            app.StartTrainingButton = uibutton(app.TrainingParamsPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.ProgressBar = uigauge(app.UIFigure, 'linear');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrainingCNN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end