classdef GreyScaleConversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GreyScaleButton         matlab.ui.control.Button
        RGBButton               matlab.ui.control.Button
        OdaberikonverzijuLabel  matlab.ui.control.Label
        Converted               matlab.ui.control.Image
        KonvertovanaslikaLabel  matlab.ui.control.Label
        OriginalnaslikaLabel    matlab.ui.control.Label
        Original                matlab.ui.control.Image
    end

    
    properties (Access = private)
        LoadedImage % Description
    end
    
    methods (Access = public)
        
        function loadImage(app, image)
        % Display the passed image in a UIAxes
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RGBButton
        function RGBButtonPushed(app, event)
            app.Converted.ImageSource = app.LoadedImage;
        end

        % Button pushed function: GreyScaleButton
        function GreyScaleButtonPushed(app, event)
            gsImage = rgb2gray(app.LoadedImage);
            % da bi konvertovao u 3-channel format jer imagesource to
            % ocekuje
            gsImage = repmat(gsImage, [1 1 3]);
            app.Converted.ImageSource = gsImage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiimage(app.UIFigure);
            app.OriginalnaslikaLabel = uilabel(app.UIFigure);
            app.OriginalnaslikaLabel.Text = 'Originalna slika';
            app.KonvertovanaslikaLabel = uilabel(app.UIFigure);
            app.KonvertovanaslikaLabel.Text = 'Konvertovana slika';
            app.Converted = uiimage(app.UIFigure);
            app.OdaberikonverzijuLabel = uilabel(app.UIFigure);
            app.OdaberikonverzijuLabel.Text = 'Odaberi konverziju';
            app.RGBButton = uibutton(app.UIFigure, 'push');
            app.RGBButton.ButtonPushedFcn = createCallbackFcn(app, @RGBButtonPushed, true);
            app.RGBButton.Text = 'RGB';
            app.GreyScaleButton = uibutton(app.UIFigure, 'push');
            app.GreyScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GreyScaleButtonPushed, true);
            app.GreyScaleButton.Text = 'GreyScale';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GreyScaleConversion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end