classdef ActiveContours < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SegmentedImage               matlab.ui.control.Image
        RunSegmentationButton        matlab.ui.control.Button
        StopCriterionEditField       matlab.ui.control.NumericEditField
        StopCriterionEditFieldLabel  matlab.ui.control.Label
        IterationSlider              matlab.ui.control.Slider
        IterationSliderLabel         matlab.ui.control.Label
        MaskShapeDropDown            matlab.ui.control.DropDown
        MaskShapeDropDownLabel       matlab.ui.control.Label
        Converted                    matlab.ui.control.Image
        KonvertovanaslikaLabel       matlab.ui.control.Label
        OriginalnaslikaLabel         matlab.ui.control.Label
        Original                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        LoadedImage % Description
        Mask % Description
    end
    
    methods (Access = public)
        
        function loadImage(app, image)
        % Display the passed image in a UIAxes
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MaskShapeDropDown
        function MaskShapeDropDownValueChanged(app, event)
            % Check if image is loaded
            if isempty(app.LoadedImage)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Get image dimensions
            [height, width, ~] = size(app.LoadedImage);
            
            % Create mask based on user selection
            if strcmp(app.MaskShapeDropDown.Value, 'Rectangle')
                app.Mask = false(height, width);
                h_start = round(height/4);
                h_end = round(3*height/4);
                w_start = round(width/4);
                w_end = round(3*width/4);
                app.Mask(h_start:h_end, w_start:w_end) = true;
            elseif strcmp(app.MaskShapeDropDown.Value, 'Ellipse')
                [rows, cols] = ndgrid(1:height, 1:width);
                center = [round(height/2), round(width/2)];
                radius = min(round(height/3), round(width/3));
                app.Mask = (rows - center(1)).^2 + (cols - center(2)).^2 <= radius^2;
            end
            
            % Create overlay image
            overlay = app.LoadedImage;
            if size(overlay, 3) == 1
                overlay = repmat(overlay, [1 1 3]);
            end
            
            % Create red mask overlay
            mask_overlay = zeros(size(overlay));
            mask_overlay(:,:,1) = app.Mask * 255;  % Red channel
            
            % Blend original image with mask
            alpha = 0.3;  % Transparency level
            blended = uint8(double(overlay) .* (1-alpha) + double(mask_overlay) .* alpha);
            
            % Display result in Image component
            app.SegmentedImage.ImageSource = blended;

        end

        % Button pushed function: RunSegmentationButton
        function RunSegmentationButtonPushed(app, event)
            % Check if the image and mask are defined
            if isempty(app.LoadedImage) || isempty(app.Mask)
                uialert(app.UIFigure, 'Postavite masku pre pokretanja algoritma.', 'Greška');
                return;
            end
        
            % Get current values from UI controls
            maxIterations = round(app.IterationSlider.Value);
            stopCriterion = app.StopCriterionEditField.Value;
        
            % Set a reasonable default value if the stop criterion is not valid
            if isempty(stopCriterion) || ~isfinite(stopCriterion)
                stopCriterion = 0.5;
            end
        
            try
                % Perform the segmentation
                segmented = activecontour(app.LoadedImage, app.Mask, maxIterations, 'Chan-Vese', 'SmoothFactor', stopCriterion);
                
                % Convert logical to uint8 for proper display
                segmentedImage = uint8(segmented) * 255;
                
                % If the original image is RGB, replicate the mask for all channels
                if size(app.LoadedImage, 3) == 3
                    segmentedImage = repmat(segmentedImage, [1 1 3]);
                end
                
                % Update the display directly without saving to file
                app.Converted.ImageSource = segmentedImage;
                
                % Force a refresh of the display
                drawnow;
                
            catch ME
                % Handle any errors that might occur during segmentation
                uialert(app.UIFigure, ['Segmentation failed: ' ME.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiimage(app.UIFigure);
            app.OriginalnaslikaLabel = uilabel(app.UIFigure);
            app.OriginalnaslikaLabel.Text = 'Originalna slika';
            app.KonvertovanaslikaLabel = uilabel(app.UIFigure);
            app.KonvertovanaslikaLabel.Text = 'Konvertovana slika';
            app.Converted = uiimage(app.UIFigure);
            app.MaskShapeDropDownLabel = uilabel(app.UIFigure);
            app.MaskShapeDropDownLabel.Text = 'Mask Shape';
            app.MaskShapeDropDown = uidropdown(app.UIFigure);
            app.MaskShapeDropDown.Items = {'Ellipse', 'Rectangle'};
            app.MaskShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskShapeDropDownValueChanged, true);
            app.MaskShapeDropDown.Placeholder = 'Select mask';
            app.MaskShapeDropDown.Value = 'Ellipse';
            app.IterationSliderLabel = uilabel(app.UIFigure);
            app.IterationSliderLabel.Text = 'Iteration';
            app.IterationSlider = uislider(app.UIFigure);
            app.IterationSlider.Limits = [10 500];
            app.IterationSlider.Value = 10;
            app.StopCriterionEditFieldLabel = uilabel(app.UIFigure);
            app.StopCriterionEditFieldLabel.Text = 'Stop Criterion';
            app.StopCriterionEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunSegmentationButton = uibutton(app.UIFigure, 'push');
            app.RunSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSegmentationButtonPushed, true);
            app.RunSegmentationButton.Text = 'Run Segmentation';
            app.SegmentedImage = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ActiveContours

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end