classdef Filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PrimeninekiodfilteraLabel     matlab.ui.control.Label
        SlikasaprimenjenimfilteromLabel  matlab.ui.control.Label
        FilteredImage                 matlab.ui.control.Image
        DFilteringBM3DButton          matlab.ui.control.Button
        BlockMatchingalgoritamButton  matlab.ui.control.Button
        GaussianfilterButton          matlab.ui.control.Button
        MedianfilterButton            matlab.ui.control.Button
        SlikasaumomLabel              matlab.ui.control.Label
        NoisyImage                    matlab.ui.control.Image
        OriginalnaslikaLabel          matlab.ui.control.Label
        PrimeniumButton               matlab.ui.control.Button
        OdaberitejainuumaSlider       matlab.ui.control.Slider
        OdaberitejainuumaSliderLabel  matlab.ui.control.Label
        Original                      matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

   properties (Access = private)
        LoadedImage % Description
        noiseValue % Description
    end
    
    methods (Access = public)
        
        function loadImage(app, image)
        % Display the passed image in a UIAxes
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: OdaberitejainuumaSlider
        function OdaberitejainuumaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.noiseValue = event.Value;
        end

        % Button pushed function: PrimeniumButton
        function PrimeniumButtonPushed(app, event)
            % Učitaj originalnu sliku
            originalImage = app.LoadedImage;
           % Dobij vrednost jačine šuma sa slajdera
            noiseLevel = app.OdaberitejainuumaSlider.Value;  % Vrednost sa slajdera
            noiseLevel = noiseLevel / 10;
            % Dodaj Salt & Pepper šum na sliku
            noisyImage = imnoise(originalImage, 'salt & pepper', noiseLevel);
            % Prikazivanje šumovite slike u aplikaciji
            app.NoisyImage.ImageSource = noisyImage;
        end

        % Button pushed function: MedianfilterButton
        function MedianfilterButtonPushed(app, event)
           noisyImage = app.NoisyImage.ImageSource;
            % Proveri da li je slika u boji (RGB)
            if size(noisyImage, 3) == 3
                % Razdvoj RGB kanale
                redChannel = noisyImage(:, :, 1);
                greenChannel = noisyImage(:, :, 2);
                blueChannel = noisyImage(:, :, 3);
                % Primeni median filter na svaki kanal
                filteredRed = medfilt2(redChannel, [3 3]);
                filteredGreen = medfilt2(greenChannel, [3 3]);
                filteredBlue = medfilt2(blueChannel, [3 3]);
                % Kombinuj filtrirane kanale nazad u RGB sliku
                filteredImage = cat(3, filteredRed, filteredGreen, filteredBlue);
            else
                % Ako je slika u nijansama sive, direktno primeni median filter
                filteredImage = medfilt2(noisyImage, [3 3]);
            end
            % Prikazivanje filtrirane slike u aplikaciji
            app.FilteredImage.ImageSource = filteredImage;
        end

        % Button pushed function: GaussianfilterButton
        function GaussianfilterButtonPushed(app, event)
            % Učitaj šumovitu sliku
            noisyImage = app.NoisyImage.ImageSource;
    
            % Definiši Gaussian kernel
            gaussianKernelSize = 3; % Veličina kernela (npr. 3x3)
            gaussianSigma = 0.5;    % Standardna devijacija za Gaussian filter
            gaussianKernel = fspecial('gaussian', gaussianKernelSize, gaussianSigma);


            if size(noisyImage, 3) == 3
                % Primena Gaussian filtera na svaki kanal RGB slike
                filteredR = imgaussfilt(noisyImage(:, :, 1), 2); % Crveni kanal
                filteredG = imgaussfilt(noisyImage(:, :, 2), 2); % Zeleni kanal
                filteredB = imgaussfilt(noisyImage(:, :, 3), 2); % Plavi kanal
            end
                % Kombinovanje kanala nazad u RGB sliku
                filteredImage = cat(3, filteredR, filteredG, filteredB);
        
            % Prikazivanje filtrirane slike u aplikaciji
            app.FilteredImage.ImageSource = filteredImage;
        end

        % Button pushed function: BlockMatchingalgoritamButton
        function BlockMatchingalgoritamButtonPushed(app, event)
           % Proverite da li su slike učitane
            if ~isempty(app.Original.ImageSource) && ~isempty(app.NoisyImage.ImageSource)
            % Učitajte slike
            originalImage = app.Original.ImageSource; % Originalna slika (RGB)
            noisyImage = app.NoisyImage.ImageSource; % Šumna slika (RGB)
            
            % Proverite da li su slike RGB
            if size(originalImage, 3) == 3 && size(noisyImage, 3) == 3
                % Parametri algoritma
                blockSize = 16; % Veličina bloka (npr. 16x16)
                searchRange = 8; % Opseg pretrage (npr. ±8 piksela)
            
                % Kreiranje prazne matrice za filtriranu sliku
                filteredImage = zeros(size(noisyImage), 'like', noisyImage);
            
                % Iteracija kroz svaki kanal (R, G, B)
                for channel = 1:3
                    % Uzmite odgovarajući kanal iz RGB slika
                    originalChannel = originalImage(:, :, channel);
                    noisyChannel = noisyImage(:, :, channel);
            
                    % Dimenzije slike
                    [height, width] = size(originalChannel);
            
                    % Iteracija kroz blokove slike
                    for i = 1:blockSize:height-blockSize+1
                        for j = 1:blockSize:width-blockSize+1
                            % Izdvajanje bloka iz originalnog kanala
                            refBlock = originalChannel(i:i+blockSize-1, j:j+blockSize-1);
            
                            % Inicijalizacija minimalnog SAD i pozicije
                            minSAD = inf;
                            bestMatch = [0, 0];
            
                            % Pretraga u okviru opsega
                            for m = -searchRange:searchRange
                                for n = -searchRange:searchRange
                                    % Koordinate u šumnoj slici
                                    currRow = i + m;
                                    currCol = j + n;
            
                                    % Provera da li je blok unutar granica slike
                                    if currRow > 0 && currRow+blockSize-1 <= height && ...
                                       currCol > 0 && currCol+blockSize-1 <= width
                                        % Izdvajanje bloka iz šumnog kanala
                                        noisyBlock = noisyChannel(currRow:currRow+blockSize-1, currCol:currCol+blockSize-1);
            
                                        % Izračunavanje SAD
                                        SAD = sum(abs(refBlock(:) - noisyBlock(:)));
            
                                        % Ažuriranje najboljeg meča
                                        if SAD < minSAD
                                            minSAD = SAD;
                                            bestMatch = [m, n];
                                        end
                                    end
                                end
                            end
            
                            % Kopiranje najboljeg bloka u filtriranu sliku
                            bestRow = i + bestMatch(1);
                            bestCol = j + bestMatch(2);
                            filteredImage(i:i+blockSize-1, j:j+blockSize-1, channel) = ...
                                noisyChannel(bestRow:bestRow+blockSize-1, bestCol:bestCol+blockSize-1);
                        end
                    end
                end
            
                % Prikaz filtrirane slike u RGB formatu
                app.FilteredImage.ImageSource = filteredImage;
            
            else
                % Ako slike nisu RGB, prijavite grešku
                uialert(app.UIFigure, 'Jedna ili obe slike nisu RGB!', 'Greška');
            end
            else
            % Ako slike nisu učitane, prijavite grešku
            uialert(app.UIFigure, 'Obe slike moraju biti učitane!', 'Greška');
            end
        end

        % Button pushed function: DFilteringBM3DButton
        function DFilteringBM3DButtonPushed(app, event)
         noisyImage = app.NoisyImage.ImageSource;

        % Define the noise standard deviation (you can modify this value as needed)
        sigma = 25; % Standard deviation of Gaussian noise
        
        % Check if the image is in color (RGB)
        if size(noisyImage, 3) == 3
            % Split the RGB channels
            redChannel = noisyImage(:, :, 1);
            greenChannel = noisyImage(:, :, 2);
            blueChannel = noisyImage(:, :, 3);
        
            % Apply BM3D denoising to each channel separately
            % Convert the image to double in the range [0,1] before processing
            redChannel = im2double(redChannel);
            greenChannel = im2double(greenChannel);
            blueChannel = im2double(blueChannel);
            
            % Calculate the noise power spectral density for each channel
            sigma_psd_red = sigma^2 * ones(size(redChannel)); % PSD for the red channel
            sigma_psd_green = sigma^2 * ones(size(greenChannel)); % PSD for the green channel
            sigma_psd_blue = sigma^2 * ones(size(blueChannel)); % PSD for the blue channel
            
            % Apply BM3D to each channel
            filteredRed = BM3D(redChannel, sigma_psd_red, 'np'); 
            filteredGreen = BM3D(greenChannel, sigma_psd_green, 'np');
            filteredBlue = BM3D(blueChannel, sigma_psd_blue, 'np');
            
            % Combine the filtered channels back into an RGB image
            filteredImage = cat(3, filteredRed, filteredGreen, filteredBlue);
        else
            % If the image is grayscale, directly apply BM3D
            % Convert to double in the range [0,1]
            noisyImage = im2double(noisyImage);
            
            % Calculate the noise power spectral density
            sigma_psd = sigma^2 * ones(size(noisyImage)); % PSD for grayscale image
            
            % Apply BM3D denoising
            filteredImage = BM3D(noisyImage, sigma_psd, 'np');
        end
        
        % Display the filtered image in the app
        app.FilteredImage.ImageSource = filteredImage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Original = uiimage(app.UIFigure);
            app.OdaberitejainuumaSliderLabel = uilabel(app.UIFigure);
            app.OdaberitejainuumaSliderLabel.Text = 'Odaberite jačinu šuma';
            app.OdaberitejainuumaSlider = uislider(app.UIFigure);
            app.OdaberitejainuumaSlider.Limits = [0 10];
            app.OdaberitejainuumaSlider.ValueChangingFcn = createCallbackFcn(app, @OdaberitejainuumaSliderValueChanging, true);
            app.PrimeniumButton = uibutton(app.UIFigure, 'push');
            app.PrimeniumButton.ButtonPushedFcn = createCallbackFcn(app, @PrimeniumButtonPushed, true);
            app.PrimeniumButton.Text = 'Primeni šum';
            app.OriginalnaslikaLabel = uilabel(app.UIFigure);
            app.OriginalnaslikaLabel.Text = 'Originalna slika';
            app.NoisyImage = uiimage(app.UIFigure);
            app.SlikasaumomLabel = uilabel(app.UIFigure);
            app.SlikasaumomLabel.Text = 'Slika sa šumom';
            app.MedianfilterButton = uibutton(app.UIFigure, 'push');
            app.MedianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianfilterButtonPushed, true);
            app.MedianfilterButton.Text = 'Median filter';
            app.GaussianfilterButton = uibutton(app.UIFigure, 'push');
            app.GaussianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianfilterButtonPushed, true);
            app.GaussianfilterButton.Text = 'Gaussian filter';
            app.BlockMatchingalgoritamButton = uibutton(app.UIFigure, 'push');
            app.BlockMatchingalgoritamButton.ButtonPushedFcn = createCallbackFcn(app, @BlockMatchingalgoritamButtonPushed, true);
            app.BlockMatchingalgoritamButton.Text = 'Block-Matching algoritam';
            app.DFilteringBM3DButton = uibutton(app.UIFigure, 'push');
            app.DFilteringBM3DButton.ButtonPushedFcn = createCallbackFcn(app, @DFilteringBM3DButtonPushed, true);
            app.DFilteringBM3DButton.Text = '3D Filtering (BM3D)';
            app.FilteredImage = uiimage(app.UIFigure);
            app.SlikasaprimenjenimfilteromLabel = uilabel(app.UIFigure);
            app.SlikasaprimenjenimfilteromLabel.Text = 'Slika sa primenjenim filterom';
            app.PrimeninekiodfilteraLabel = uilabel(app.UIFigure);
            app.PrimeninekiodfilteraLabel.Text = 'Primeni neki od filtera';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end