classdef Options < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpcijePanel                     matlab.ui.container.Panel
        RotacijaslikeButton             matlab.ui.control.Button
        PrimenaalgoritmaHistogramEqualizationButton  matlab.ui.control.Button
        KonverzijaslikeucrnobeluButton  matlab.ui.control.Button
        KonverzijaslikeurazliiteformateRGBHSVCIELabYCbCrButton  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        UitajslikuButton                matlab.ui.control.Button
        MolimovasprvouitajteslikukojubistedaobraditeLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageLoaded = false; % Description
        ImageFile % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UitajslikuButton
        function UitajslikuButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.png;*.jpg;*.jpeg', "Izaberi sliku");

            if isequal(filename, 0)
                figure(app.UIFigure);
                return;
            end

            filename = strcat(pathname, filename);
            app.ImageFile=imread(filename);
            app.Image.ImageSource = app.ImageFile;
            app.ImageLoaded = true;

            figure(app.UIFigure);
        end

        % Button pushed function: 
        % KonverzijaslikeurazliiteformateRGBHSVCIELabYCbCrButton
        function KonverzijaslikeurazliiteformateRGBHSVCIELabYCbCrButtonPushed(app, event)
            if app.ImageLoaded
                 konverzije = ColorFormatsConversion(); 

                 konverzije.loadImage(app.ImageFile)
            else
                % Show a warning message if no image is loaded
                uialert(app.UIFigure, 'Morate izabrati sliku prvo!', 'Warning');
            end
        end

        % Button pushed function: KonverzijaslikeucrnobeluButton
        function KonverzijaslikeucrnobeluButtonPushed(app, event)
            if app.ImageLoaded
                 konverzije = GreyScaleConversion(); 

                 konverzije.loadImage(app.ImageFile)
            else
                % Show a warning message if no image is loaded
                uialert(app.UIFigure, 'Morate izabrati sliku prvo!', 'Warning');
            end
        end

        % Button pushed function: 
        % PrimenaalgoritmaHistogramEqualizationButton
        function PrimenaalgoritmaHistogramEqualizationButtonPushed(app, event)
            if app.ImageLoaded
                konverzije = HistogramEqualization();
                konverzije.loadImage(app.ImageFile);
            else
                uialert(app.UIFigure, 'Morate izabrati sliku prvo!', 'Warning');
            end
        end

        % Button pushed function: RotacijaslikeButton
        function RotacijaslikeButtonPushed(app, event)
            if app.ImageLoaded
                rotacija = ImageRotation();
                rotacija.loadImage(app.ImageFile);
            else
                uialert(app.UIFigure, 'Morate izabrati sliku prvo!', 'Warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MolimovasprvouitajteslikukojubistedaobraditeLabel = uilabel(app.UIFigure);
            app.MolimovasprvouitajteslikukojubistedaobraditeLabel.Text = 'Molimo vas prvo učitajte sliku koju bi ste da obradite';
            app.UitajslikuButton = uibutton(app.UIFigure, 'push');
            app.UitajslikuButton.ButtonPushedFcn = createCallbackFcn(app, @UitajslikuButtonPushed, true);
            app.UitajslikuButton.Text = 'Učitaj sliku';
            app.Image = uiimage(app.UIFigure);
            app.OpcijePanel = uipanel(app.UIFigure);
            app.OpcijePanel.Title = 'Opcije';
            app.KonverzijaslikeurazliiteformateRGBHSVCIELabYCbCrButton = uibutton(app.OpcijePanel, 'push');
            app.KonverzijaslikeurazliiteformateRGBHSVCIELabYCbCrButton.ButtonPushedFcn = createCallbackFcn(app, @KonverzijaslikeurazliiteformateRGBHSVCIELabYCbCrButtonPushed, true);
            app.KonverzijaslikeurazliiteformateRGBHSVCIELabYCbCrButton.Text = 'Konverzija slike u različite formate (RGB, HSV, CIE Lab, YCbCr)';
            app.KonverzijaslikeucrnobeluButton = uibutton(app.OpcijePanel, 'push');
            app.KonverzijaslikeucrnobeluButton.ButtonPushedFcn = createCallbackFcn(app, @KonverzijaslikeucrnobeluButtonPushed, true);
            app.KonverzijaslikeucrnobeluButton.Text = 'Konverzija slike u crno-belu';
            app.PrimenaalgoritmaHistogramEqualizationButton = uibutton(app.OpcijePanel, 'push');
            app.PrimenaalgoritmaHistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @PrimenaalgoritmaHistogramEqualizationButtonPushed, true);
            app.PrimenaalgoritmaHistogramEqualizationButton.Text = 'Primena algoritma Histogram Equalization';
            app.RotacijaslikeButton = uibutton(app.OpcijePanel, 'push');
            app.RotacijaslikeButton.ButtonPushedFcn = createCallbackFcn(app, @RotacijaslikeButtonPushed, true);
            app.RotacijaslikeButton.Text = 'Rotacija slike';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Options

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end