classdef ResizingResampling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ScaleFactorEditField       matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel  matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        InterpolationMethodDropDownLabel  matlab.ui.control.Label
        ApplyResizeButton          matlab.ui.control.Button
        NewHeightEditField         matlab.ui.control.NumericEditField
        NewHeightEditFieldLabel    matlab.ui.control.Label
        NewWidthEditField          matlab.ui.control.NumericEditField
        NewWidthEditFieldLabel     matlab.ui.control.Label
        OriginalImageLabel         matlab.ui.control.Label
        Original                   matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
    end

    properties (Access = private)
        LoadedImage % Description
    end
    
    methods (Access = public)
        
        function loadImage(app, image)
        % Display the passed image in a UIAxes
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyResizeButton
        function ApplyResizeButtonPushed(app, event)
      originalImage = app.LoadedImage; 
    
    % Preuzimanje unetih dimenzija od strane korisnika
    width = app.NewWidthEditField.Value;  % Preuzimanje širine 
    height = app.NewHeightEditField.Value; % Preuzimanje visine 
    scaleFactor = app.ScaleFactorEditField.Value; % Preuzimanje faktora skaliranja
    method = app.DropDown.Value;  % Odabrana metoda interpolacije
    if isempty(method) || ~ismember(method, {'nearest', 'bilinear', 'bicubic'})
        method = 'bilinear';  % Ako je metoda prazna ili nije validna, postavi na 'bilinear'
    end
    if scaleFactor ~= 1
        newHeight = round(size(originalImage, 1) * scaleFactor);
        newWidth = round(size(originalImage, 2) * scaleFactor);
    else
        newHeight = height; 
        newWidth = width;
    end
    if mod(newHeight, 2) ~= 0
        newHeight = newHeight + 1; 
    end
    if mod(newWidth, 2) ~= 0
        newWidth = newWidth + 1;  
    end
    if newWidth <= 0 || newHeight <= 0
        uialert(app.UIFigure, 'Invalid dimensions, please check input values!', 'Error');
        return; 
    end
    try
        resizedImage = imresize(originalImage, [newHeight newWidth], method);
    catch ME
        disp('Error during image resize:');
        disp(ME.message);
        uialert(app.UIFigure, ['Error: ', ME.message], 'Error');  % Prikazuje grešku u alertu
        return;
    end
    imshow(resizedImage, 'Parent', app.UIAxes);
    title(app.UIAxes, sprintf('%dx%d', newWidth, newHeight));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Scaled Image')
            zlabel(app.UIAxes, 'Z')
            app.Original = uiimage(app.UIFigure);
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.NewWidthEditFieldLabel = uilabel(app.UIFigure);
            app.NewWidthEditFieldLabel.Text = 'New Width';
            app.NewWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewHeightEditFieldLabel = uilabel(app.UIFigure);
            app.NewHeightEditFieldLabel.Text = 'New Height';
            app.NewHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ApplyResizeButton = uibutton(app.UIFigure, 'push');
            app.ApplyResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyResizeButtonPushed, true);
            app.ApplyResizeButton.Text = 'Apply Resize';
            app.InterpolationMethodDropDownLabel = uilabel(app.UIFigure);
            app.InterpolationMethodDropDownLabel.Text = 'Interpolation Method';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'bilinear', 'bicubic', 'nearest '};
            app.DropDown.Value = 'bilinear';
            app.ScaleFactorEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor ';
            app.ScaleFactorEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResizingResampling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end