classdef CNNArchitectures < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CNNArchitecturesUIFigure   matlab.ui.Figure
        dupliklikLabel             matlab.ui.control.Label
        IzaberiButton              matlab.ui.control.Button
        DetaljiLabel               matlab.ui.control.Label
        IzaberitearhitekturuLabel  matlab.ui.control.Label
        ArchitectureDetails        matlab.ui.control.TextArea
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = public)
        ArchitecturesData  table
        choosenArch
        ParentApp
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.ColumnName = {'Architecture', 'Layers', 'Description'};
            architectures = {'LeNet', 'AlexNet', 'VGG-16', 'VGG-19', 'ResNet', ...
                 'GoogLeNet', 'Inception-v3', 'MobileNet-v2', 'SqueezeNet', 'EfficientNet', 'YOLOv4'};

            layers = [7, 8, 16, 19, 50, ...
                      22, 48, 53, 18, NaN, NaN];
            
            description = {'Simple architecture for digit recognition', ...
                           'First deep CNN, introduced in ImageNet', ...
                           'Deep CNN with 16 layers', ...
                           'Deep CNN with 19 layers', ...
                           'Uses residual connections for deeper networks', ...
                           'Inception modules with auxiliary classifiers (ImageNet 2014 winner)', ...
                           'Advanced Inception with factorized convolutions and RMSProp', ...
                           'Lightweight CNN with depthwise separable convolutions', ...
                           'Compact CNN with fire modules for small models', ...
                           'Efficient CNN with compound scaling for depth, width, and resolution', ...
                           'Real-time object detection network using CSPDarkNet'};
            
            tableData = [architectures', num2cell(layers)', description'];
            
            app.UITable.Data = tableData;
            uiwait(app.CNNArchitecturesUIFigure);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row = indices(1);
                col = indices(2)
                
                clickedData = app.UITable.Data{row, col};
                details = "";
                if ischar(clickedData) || isstring(clickedData)
                    selectedArch = string(clickedData);
                    
                    switch selectedArch
                        case "LeNet"
                            details = "LeNet-5" + newline + ...
                                      "Layers: 7" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Conv (5x5)" + newline + ...
                                      "- Pooling" + newline + ...
                                      "- Fully Connected" + newline + ...
                                      "Use Case: Digit recognition.";
                            app.choosenArch = "LeNet";
                        case "AlexNet"
                            details = "AlexNet" + newline + ...
                                      "Layers: 8" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Conv (11x11, 5x5, 3x3)" + newline + ...
                                      "- ReLU activation" + newline + ...
                                      "- Dropout" + newline + ...
                                      "Use Case: Image classification.";
                            app.choosenArch = "AlexNet";
                        case "VGG-16"
                            details = "VGG-16" + newline + ...
                                      "Layers: 16" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Conv (3x3)" + newline + ...
                                      "- Pooling" + newline + ...
                                      "- Fully Connected" + newline + ...
                                      "Use Case: Deep image classification.";
                            app.choosenArch = "VGG-16";
                        case "VGG-19"
                            details = "VGG-19" + newline + ...
                                      "Layers: 19" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Conv (3x3)" + newline + ...
                                      "- Pooling" + newline + ...
                                      "- Fully Connected" + newline + ...
                                      "Use Case: Deep image classification.";
                            app.choosenArch = "VGG-19";
                        case "ResNet"
                            details = "ResNet" + newline + ...
                                      "Layers: 50" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Conv (3x3, 1x1)" + newline + ...
                                      "- Residual connections" + newline + ...
                                      "Use Case: Very deep network for image tasks.";
                            app.choosenArch = "ResNet";
                        case "GoogLeNet"
                            details = "GoogLeNet" + newline + ...
                                      "Layers: 22" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Inception modules" + newline + ...
                                      "- Auxiliary classifiers" + newline + ...
                                      "Use Case: Image classification (ImageNet challenge winner 2014).";
                            app.choosenArch = "GoogLeNet";
                        case "Inception-v3"
                            details = "Inception-v3" + newline + ...
                                      "Layers: 48" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Inception modules" + newline + ...
                                      "- Factorized convolutions" + newline + ...
                                      "- RMSProp optimizer" + newline + ...
                                      "Use Case: Advanced image classification.";
                            app.choosenArch = "Inception-v3";
                        case "MobileNet-v2"
                            details = "MobileNet-v2" + newline + ...
                                      "Layers: 53" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Depthwise separable convolutions" + newline + ...
                                      "- Bottleneck layers" + newline + ...
                                      "Use Case: Lightweight image classification for mobile devices.";
                            app.choosenArch = "MobileNet-v2";
                        case "SqueezeNet"
                            details = "SqueezeNet" + newline + ...
                                      "Layers: 18" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Fire modules (squeeze and expand)" + newline + ...
                                      "Use Case: Small model for image classification.";
                            app.choosenArch = "SqueezeNet";
                        case "EfficientNet"
                            details = "EfficientNet" + newline + ...
                                      "Layers: Variable (B0-B7)" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Compound scaling of depth, width, and resolution" + newline + ...
                                      "Use Case: Efficient and accurate image classification.";
                            app.choosenArch = "EfficientNet";
                        case "YOLOv4"
                            details = "YOLOv4" + newline + ...
                                      "Layers: Customizable (varies by implementation)" + newline + ...
                                      "Structure:" + newline + ...
                                      "- Detection head for object localization" + newline + ...
                                      "- CSPDarkNet backbone" + newline + ...
                                      "Use Case: Real-time object detection.";
                            app.choosenArch = "YOLOv4";
                        otherwise
                            details = "No details available.";
                    end
            
                    app.ArchitectureDetails.Value = details;
                else
                    app.ArchitectureDetails.Value = "Please select a valid architecture name.";
                end

                app.ArchitectureDetails.Value = splitlines(details);
            end
        end

        % Button pushed function: IzaberiButton
        function IzaberiButtonPushed(app, event)
            app.choosenArch = app.choosenArch;
            uiresume(app.CNNArchitecturesUIFigure);
        end

        % Close request function: CNNArchitecturesUIFigure
        function CNNArchitecturesUIFigureCloseRequest(app, event)
            uiresume(app.CNNArchitecturesUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CNNArchitecturesUIFigure = uifigure('Visible', 'off');
            app.CNNArchitecturesUIFigure.Name = 'CNN Architectures';
            app.CNNArchitecturesUIFigure.CloseRequestFcn = createCallbackFcn(app, @CNNArchitecturesUIFigureCloseRequest, true);
            app.UITable = uitable(app.CNNArchitecturesUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Halo'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ArchitectureDetails = uitextarea(app.CNNArchitecturesUIFigure);
            app.IzaberitearhitekturuLabel = uilabel(app.CNNArchitecturesUIFigure);
            app.IzaberitearhitekturuLabel.Text = 'Izaberite arhitekturu';
            app.DetaljiLabel = uilabel(app.CNNArchitecturesUIFigure);
            app.DetaljiLabel.Text = 'Detalji';
            app.IzaberiButton = uibutton(app.CNNArchitecturesUIFigure, 'push');
            app.IzaberiButton.ButtonPushedFcn = createCallbackFcn(app, @IzaberiButtonPushed, true);
            app.IzaberiButton.Text = 'Izaberi';
            app.dupliklikLabel = uilabel(app.CNNArchitecturesUIFigure);
            app.dupliklikLabel.Text = '(dupli klik)';
            % Show the figure after all components are created
            app.CNNArchitecturesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CNNArchitectures

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CNNArchitecturesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CNNArchitecturesUIFigure)
        end
    end
end