classdef SemanticSegmentationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SegmentationControlPanel  matlab.ui.container.Panel
        LoadImagesButton          matlab.ui.control.Button
        RunSegmentationButton     matlab.ui.control.Button
        LoadModelButton           matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
                [file, path] = uigetfile('*.mat', 'Odaberi model (.mat)');
    useDummy = false;

    if isequal(file,0)
        % Ako ništa nije odabrano – koristi dummy
        modelName = 'Dummy Segmentation Model';
        useDummy = true;
    else
        % Pokušaj učitati model iz fajla
        S = load(fullfile(path, file));
        if isfield(S, "lgraphUNet")
            lgraph = S.lgraphUNet;
            modelName = 'U-Net';
        elseif isfield(S, "lgraphSegNet")
            lgraph = S.lgraphSegNet;
            modelName = 'SegNet';
        else
            useDummy = true;
            modelName = 'Dummy Segmentation Model';
        end

        if ~useDummy
            try
                net = assembleNetwork(lgraph);
            catch
                warning('Nevalidan model u .mat fajlu! Kreira se dummy model.');
                useDummy = true;
            end
        end
    end

    if useDummy
        % Kreiranje totalnog dummy modela
        inputSize = [256 256 3];
        numClasses = 3;
        classes = ["class1","class2","class3"];

        input = imageInputLayer(inputSize,'Name','input','Normalization','none');

        conv1 = convolution2dLayer(3,8,'Padding','same','Name','conv1');
        conv1.Weights = randn([3 3 3 8])*0.01; conv1.Bias = zeros([1 1 8]);

        conv2 = convolution2dLayer(3,16,'Padding','same','Name','conv2');
        conv2.Weights = randn([3 3 8 16])*0.01; conv2.Bias = zeros([1 1 16]);

        conv3 = convolution2dLayer(3,32,'Padding','same','Name','conv3');
        conv3.Weights = randn([3 3 16 32])*0.01; conv3.Bias = zeros([1 1 32]);

        up1 = transposedConv2dLayer(4,16,'Stride',2,'Cropping','same','Name','up1');
        up1.Weights = randn([4 4 16 32])*0.01; up1.Bias = zeros([1 1 16]);

        up2 = transposedConv2dLayer(4,8,'Stride',2,'Cropping','same','Name','up2');
        up2.Weights = randn([4 4 8 16])*0.01; up2.Bias = zeros([1 1 8]);

        conv_final = convolution2dLayer(1,numClasses,'Padding','same','Name','conv_final');
        conv_final.Weights = randn([1 1 8 numClasses])*0.01; conv_final.Bias = zeros([1 1 numClasses]);

        layers = [
            input
            conv1
            reluLayer('Name','relu1')
            maxPooling2dLayer(2,'Stride',2,'Name','pool1')
            conv2
            reluLayer('Name','relu2')
            maxPooling2dLayer(2,'Stride',2,'Name','pool2')
            conv3
            reluLayer('Name','relu3')
            up1
            reluLayer('Name','relu_up1')
            up2
            reluLayer('Name','relu_up2')
            conv_final
            softmaxLayer('Name','softmax')
            pixelClassificationLayer('Classes',classes,'Name','pixel_labels')
        ];

        net = assembleNetwork(layerGraph(layers));
    end

    % Sačuvaj mrežu u UserData
    app.UIFigure.UserData.SegmentationModel = net;
    app.UIFigure.UserData.ModelName = modelName;

    uialert(app.UIFigure, ...
        sprintf('Model "%s" je spreman za segmentaciju!', modelName), ...
        'Info');
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
[file, path] = uigetfile({'*.jpg;*.png;*.jpeg','Slike'}, ...
        'Odaberi slike', 'MultiSelect', 'on');
    
    if isequal(file, 0)
        return;
    end

    if ischar(file)
        file = {file};
    end

    % Učitaj sve slike
    images = cell(1, numel(file));
    for i = 1:numel(file)
        try
            img = imread(fullfile(path, file{i}));
            
            % Ako je slika prevelika, smanji je radi performansi
            if size(img, 1) > 1024 || size(img, 2) > 1024
                scale = 1024 / max(size(img, 1), size(img, 2));
                img = imresize(img, scale);
            end
            
            images{i} = img;
        catch
            uialert(app.UIFigure, sprintf('Greška pri učitavanju: %s', file{i}), 'Upozorenje');
        end
    end

    % Sačuvaj u UserData
    app.UIFigure.UserData.Images = images;
    app.UIFigure.UserData.ImageFiles = file;
    app.UIFigure.UserData.ImagePath = path;

    % Prikaz prve slike
    if ~isempty(images)
        imshow(images{1}, 'Parent', app.UIAxes);
        title(app.UIAxes, file{1}, 'Interpreter', 'none');
    end

    uialert(app.UIFigure, sprintf('Učitano je %d slika.', numel(file)), 'Info');
        end

        % Button pushed function: RunSegmentationButton
        function RunSegmentationButtonPushed(app, event)
  % Proveri slike
    if ~isfield(app.UIFigure.UserData,'Images') || isempty(app.UIFigure.UserData.Images)
        uialert(app.UIFigure, 'Nema učitanih slika!', 'Greška');
        return;
    end

    % Proveri model
    if ~isfield(app.UIFigure.UserData, 'SegmentationModel')
        uialert(app.UIFigure, 'Nije učitan nijedan model!', 'Greška');
        return;
    end

    % Dohvati podatke
    images = app.UIFigure.UserData.Images;
    imageFiles = app.UIFigure.UserData.ImageFiles;
    imagePath = app.UIFigure.UserData.ImagePath;
    net = app.UIFigure.UserData.SegmentationModel;
    inputSize = net.Layers(1).InputSize;

    % Folder za rezultate
    resultsFolder = fullfile(imagePath, 'Results');
    if ~exist(resultsFolder, 'dir')
        mkdir(resultsFolder);
    end

    % Prolazak kroz slike
    for i = 1:numel(images)
        img = images{i};
        imgResized = imresize(img, inputSize(1:2));

        % Segmentacija
        C = semanticseg(imgResized, net);

        % Prikaz u GUI
        imshow(imgResized, 'Parent', app.UIAxes);
        title(app.UIAxes, imageFiles{i}, 'Interpreter', 'none');
        imshow(labeloverlay(imgResized, C), 'Parent', app.UIAxes2);
        title(app.UIAxes2, sprintf('Result (%s)', ...
            app.UIFigure.UserData.ModelName));
        drawnow;

        % Čuvanje maske
        maskFile = fullfile(resultsFolder, ['mask_' imageFiles{i}]);
        imwrite(uint8(C), maskFile);

        % Ako postoji ground truth – izračunaj IoU
        if isfield(app.UIFigure.UserData, 'GroundTruth')
            GT = app.UIFigure.UserData.GroundTruth{i};
            metrics = jaccard(C, GT);
            fprintf('IoU za sliku %s: %.3f\n', imageFiles{i}, mean(metrics));
        end
    end

    uialert(app.UIFigure, ...
        sprintf('Segmentacija svih %d slika završena.\nRezultati su u "%s".', ...
        numel(images), resultsFolder), 'Info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SegmentationControlPanel = uipanel(app.UIFigure);
            app.SegmentationControlPanel.Title = 'Segmentation Control';
            app.LoadModelButton = uibutton(app.SegmentationControlPanel, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.RunSegmentationButton = uibutton(app.SegmentationControlPanel, 'push');
            app.RunSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSegmentationButtonPushed, true);
            app.RunSegmentationButton.Text = 'Run Segmentation';
            app.LoadImagesButton = uibutton(app.SegmentationControlPanel, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Image(s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SemanticSegmentationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end