classdef TrainingCNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TitleLabel                  matlab.ui.control.Label
        ConfigPanel                 matlab.ui.container.Panel
        ArchNameLabel               matlab.ui.control.Label
        ArchValueLabel              matlab.ui.control.Label
        DatasetNameLabel            matlab.ui.control.Label
        DatasetValueLabel           matlab.ui.control.Label
        InputNameLabel              matlab.ui.control.Label
        InputValueLabel             matlab.ui.control.Label
        NormNameLabel               matlab.ui.control.Label
        NormValueLabel              matlab.ui.control.Label
        HardwarePanel               matlab.ui.container.Panel
        UseGPUCheckBox              matlab.ui.control.CheckBox
        GPUInfoLabel                matlab.ui.control.Label
        TrainingParamsPanel         matlab.ui.container.Panel
        LearningRateLabel           matlab.ui.control.Label
        LearningRateEditField       matlab.ui.control.NumericEditField
        BatchSizeLabel              matlab.ui.control.Label
        BatchSizeEditField          matlab.ui.control.NumericEditField
        EpochsLabel                 matlab.ui.control.Label
        EpochsSpinner               matlab.ui.control.Spinner
        WeightDecayLabel            matlab.ui.control.Label
        WeightDecayEditField        matlab.ui.control.NumericEditField
        OptimizerLabel              matlab.ui.control.Label
        OptimizerDropDown           matlab.ui.control.DropDown
        MomentumLabel               matlab.ui.control.Label
        MomentumEditField           matlab.ui.control.NumericEditField
        Beta1Label                  matlab.ui.control.Label
        Beta1EditField              matlab.ui.control.NumericEditField
        Beta2Label                  matlab.ui.control.Label
        Beta2EditField              matlab.ui.control.NumericEditField
        RMSDecayLabel               matlab.ui.control.Label
        RMSDecayEditField           matlab.ui.control.NumericEditField
        EpsilonLabel                matlab.ui.control.Label
        EpsilonEditField            matlab.ui.control.NumericEditField
        ValidationPanel             matlab.ui.container.Panel
        ValidationSplitLabel        matlab.ui.control.Label
        ValidationSplitSpinner      matlab.ui.control.Spinner
        ValidationFrequencyLabel    matlab.ui.control.Label
        ValidationFrequencySpinner  matlab.ui.control.Spinner
        ValidationPatienceLabel     matlab.ui.control.Label
        ValidationPatienceSpinner   matlab.ui.control.Spinner
        GradientThresholdLabel      matlab.ui.control.Label
        GradientThresholdEditField  matlab.ui.control.NumericEditField
        ShuffleLabel                matlab.ui.control.Label
        ShuffleDropDown             matlab.ui.control.DropDown
        PlotsLabel                  matlab.ui.control.Label
        PlotsDropDown               matlab.ui.control.DropDown
        AugmentationCheckBox        matlab.ui.control.CheckBox
        AugmentationHintLabel       matlab.ui.control.Label
        ResultsPanel                matlab.ui.container.Panel
        StatusLabel                 matlab.ui.control.Label
        TestingAccuracyLabel        matlab.ui.control.Label
        AccuracyLabel               matlab.ui.control.Label
        ValidationAccuracyLabel     matlab.ui.control.Label
        ValAccuracyValueLabel       matlab.ui.control.Label
        TrainingTimeLabel           matlab.ui.control.Label
        TrainingTimeValueLabel      matlab.ui.control.Label
        SavedModelLabel             matlab.ui.control.Label
        SavedModelPathLabel         matlab.ui.control.Label
        StartTrainingButton         matlab.ui.control.Button
    end

    properties (Access = private)
        DatasetPath
        Architecture
        InputDimensions
        Normalization = 'None'
        GPUAvailable = false
    end

    methods (Access = public)
        function loadData(app, architecture, dataset, dimensions, normalization)
            if nargin < 5 || isempty(normalization)
                normalization = 'None';
            end
            app.Architecture = char(architecture);
            app.DatasetPath = char(dataset);
            app.InputDimensions = dimensions;
            app.Normalization = char(normalization);

            [~, datasetName] = fileparts(app.DatasetPath);
            app.ArchValueLabel.Text = app.Architecture;
            app.DatasetValueLabel.Text = datasetName;

            app.InputValueLabel.Text = strjoin(string(dimensions), 'x');
            app.NormValueLabel.Text = app.Normalization;
        end
    end

    methods (Access = private)

        function p = collectParams(app)
            % Read every training control into one struct
            p.lr = app.LearningRateEditField.Value;
            p.batchSize = app.BatchSizeEditField.Value;
            p.epochs = app.EpochsSpinner.Value;
            p.weightDecay = app.WeightDecayEditField.Value;
            p.optimizer = app.OptimizerDropDown.Value;
            p.momentum = app.MomentumEditField.Value;
            p.beta1 = app.Beta1EditField.Value;
            p.beta2 = app.Beta2EditField.Value;
            p.rmsDecay = app.RMSDecayEditField.Value;
            p.epsilon = app.EpsilonEditField.Value;
            p.validationFraction = app.ValidationSplitSpinner.Value / 100;
            p.validationFrequency = app.ValidationFrequencySpinner.Value;
            p.validationPatience = app.ValidationPatienceSpinner.Value;
            if p.validationPatience == 0
                p.validationPatience = Inf; % 0 = no early stopping
            end
            p.gradientThreshold = app.GradientThresholdEditField.Value;
            if p.gradientThreshold == 0
                p.gradientThreshold = Inf; % 0 = no gradient clipping
            end
            p.shuffle = app.ShuffleDropDown.Value;
            p.plots = app.PlotsDropDown.Value;
            p.augment = app.AugmentationCheckBox.Value;
            if app.GPUAvailable && app.UseGPUCheckBox.Value
                p.executionEnvironment = 'gpu';
            else
                p.executionEnvironment = 'cpu';
            end
        end

        function probeGPU(app)
            % Enable the GPU option only when trainNetwork can actually use a CUDA GPU
            app.UseGPUCheckBox.Value = false;
            app.UseGPUCheckBox.Enable = 'off';

            hasPCT = license('test', 'Distrib_Computing_Toolbox') && ~isempty(ver('parallel'));
            if ~hasPCT
                app.GPUInfoLabel.Text = ['GPU nije dostupan: Parallel Computing Toolbox nije instaliran ' ...
                    'ili licenciran. Treniranje se izvršava na CPU.'];
                return;
            end

            try
                numGPUs = gpuDeviceCount("available");
            catch
                numGPUs = 0;
            end
            if numGPUs == 0
                app.GPUInfoLabel.Text = ['Nije pronađen podržan NVIDIA GPU (CUDA, compute capability >= 5.0). ' ...
                    'AMD, Intel i Apple GPU nisu podržani. Treniranje se izvršava na CPU.'];
                return;
            end

            if ~canUseGPU()
                app.GPUInfoLabel.Text = ['GPU je pronađen, ali ga MATLAB ne može koristiti ' ...
                    '(provjerite NVIDIA driver). Treniranje se izvršava na CPU.'];
                return;
            end

            try
                gpu = gpuDevice();
                app.GPUInfoLabel.Text = sprintf('%s\nCompute capability: %s\nMemorija: %.1f GB', ...
                    gpu.Name, gpu.ComputeCapability, gpu.TotalMemory / 1e9);
                app.GPUAvailable = true;
                app.UseGPUCheckBox.Enable = 'on';
                app.UseGPUCheckBox.Value = true;
            catch ME
                app.GPUInfoLabel.Text = sprintf('GPU nije moguće inicijalizovati: %s', ME.message);
            end
        end

        function setBusy(app, isBusy, statusText)
            % Lock the controls while training runs
            if isBusy
                state = 'off';
            else
                state = 'on';
            end
            controls = [app.TrainingParamsPanel.Children; app.ValidationPanel.Children];
            for k = 1:numel(controls)
                if isprop(controls(k), 'Enable')
                    controls(k).Enable = state;
                end
            end
            app.StartTrainingButton.Enable = state;
            % The GPU box stays disabled if probeGPU found no usable GPU
            if app.GPUAvailable
                app.UseGPUCheckBox.Enable = state;
            end
            app.StatusLabel.Text = "Status: " + statusText;
            drawnow;
        end

        function onTrainingProgress(app, info)
            % Status line update from cnn_core/trainCNNModel; the callback has already
            % set the richer "Treniranje u toku (...)" text, so only evaluation is shown
            if strcmp(info.phase, 'evaluating')
                setBusy(app, true, info.message);
            end
        end

        function result = trainAndSaveCNN(app, p, savePath)
            % The pipeline itself lives in cnn_core/, shared with experiments/
            result = trainCNNModel(app.DatasetPath, app.Architecture, app.Normalization, ...
                p, app.InputDimensions, @(info) onTrainingProgress(app, info));
            fprintf("Test Accuracy: %.2f%%\n", result.accuracy * 100);
            saveCNNModel(savePath, result);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            OptimizerDropDownValueChanged(app, []);
            probeGPU(app);
        end

        % Value changed function: OptimizerDropDown
        function OptimizerDropDownValueChanged(app, event)
            % Show only the hyperparameters the selected optimizer uses
            optimizer = app.OptimizerDropDown.Value;
            isSgdm = strcmp(optimizer, 'sgdm');
            isAdam = strcmp(optimizer, 'adam');
            isRms  = strcmp(optimizer, 'rmsprop');

            app.MomentumLabel.Visible = isSgdm;
            app.MomentumEditField.Visible = isSgdm;
            app.Beta1Label.Visible = isAdam;
            app.Beta1EditField.Visible = isAdam;
            app.Beta2Label.Visible = isAdam;
            app.Beta2EditField.Visible = isAdam;
            app.RMSDecayLabel.Visible = isRms;
            app.RMSDecayEditField.Visible = isRms;
            app.EpsilonLabel.Visible = isAdam || isRms;
            app.EpsilonEditField.Visible = isAdam || isRms;

            % Epsilon takes the first free row under the optimizer-specific fields
            if isAdam
                epsilonY = 70;
            else
                epsilonY = 106;
            end
            app.EpsilonLabel.Position(2) = epsilonY;
            app.EpsilonEditField.Position(2) = epsilonY;
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
            if isempty(app.Architecture) || isempty(app.DatasetPath)
                uialert(app.UIFigure, 'Arhitektura ili dataset nisu izabrani!', 'Warning');
                return;
            end
            p = collectParams(app);

            % Choose where to save
            [~, datasetName] = fileparts(app.DatasetPath);
            defaultName = regexprep(sprintf('%s_%s.mat', app.Architecture, datasetName), '[^\w.]', '_');
            [file, path] = uiputfile(defaultName, 'Save Trained Model As');
            if isequal(file, 0)
                uialert(app.UIFigure, 'Treniranje otkazano. Fajl nije izabran.', 'Otkazano');
                return;
            end
            savePath = fullfile(path, file);

            app.AccuracyLabel.Text = '—';
            app.ValAccuracyValueLabel.Text = '—';
            app.TrainingTimeValueLabel.Text = '—';
            app.SavedModelPathLabel.Text = '';
            setBusy(app, true, sprintf('Treniranje u toku (%s, %s)...', p.optimizer, upper(p.executionEnvironment)));

            try
                result = trainAndSaveCNN(app, p, savePath);
            catch ME
                setBusy(app, false, 'Greška tokom treniranja');
                uialert(app.UIFigure, ME.message, 'Greška');
                return;
            end

            app.AccuracyLabel.Text = sprintf('%.2f%%', result.accuracy * 100);
            if isnan(result.validationAccuracy)
                app.ValAccuracyValueLabel.Text = '—';
            else
                app.ValAccuracyValueLabel.Text = sprintf('%.2f%%', result.validationAccuracy * 100);
            end
            app.TrainingTimeValueLabel.Text = char(duration(0, 0, result.trainingTime, 'Format', 'hh:mm:ss'));
            app.SavedModelPathLabel.Text = savePath;
            setBusy(app, false, 'Treniranje završeno, model sačuvan');

            uialert(app.UIFigure, 'Treniranje završeno i model sačuvan.', 'Uspjeh', 'Icon', 'success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Treniranje CNN modela';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Treniranje CNN modela';
            %% Konfiguracija (read-only summary of the menu choices)
            app.ConfigPanel = uipanel(app.UIFigure);
            app.ConfigPanel.Title = 'Konfiguracija';
            app.ArchNameLabel = uilabel(app.ConfigPanel);
            app.ArchNameLabel.Text = 'Arhitektura:';
            app.ArchValueLabel = uilabel(app.ConfigPanel);
            app.ArchValueLabel.Text = '—';
            app.DatasetNameLabel = uilabel(app.ConfigPanel);
            app.DatasetNameLabel.Text = 'Dataset:';
            app.DatasetValueLabel = uilabel(app.ConfigPanel);
            app.DatasetValueLabel.Text = '—';
            app.InputNameLabel = uilabel(app.ConfigPanel);
            app.InputNameLabel.Text = 'Ulaz mreže:';
            app.InputValueLabel = uilabel(app.ConfigPanel);
            app.InputValueLabel.Text = '—';
            app.NormNameLabel = uilabel(app.ConfigPanel);
            app.NormNameLabel.Text = 'Normalizacija:';
            app.NormValueLabel = uilabel(app.ConfigPanel);
            app.NormValueLabel.Text = '—';
            %% Hardver
            app.HardwarePanel = uipanel(app.UIFigure);
            app.HardwarePanel.Title = 'Hardver';
            app.UseGPUCheckBox = uicheckbox(app.HardwarePanel);
            app.UseGPUCheckBox.Text = 'Koristi GPU (NVIDIA CUDA)';
            app.GPUInfoLabel = uilabel(app.HardwarePanel);
            app.GPUInfoLabel.Text = 'Provjera GPU-a...';
            %% Hiperparametri
            app.TrainingParamsPanel = uipanel(app.UIFigure);
            app.TrainingParamsPanel.Title = 'Hiperparametri';
            app.LearningRateLabel = uilabel(app.TrainingParamsPanel);
            app.LearningRateLabel.Text = 'Learning Rate:';
            app.LearningRateEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.LearningRateEditField.Limits = [1e-06 1];
            app.LearningRateEditField.ValueDisplayFormat = '%.6g';
            app.LearningRateEditField.Value = 0.001;
            app.BatchSizeLabel = uilabel(app.TrainingParamsPanel);
            app.BatchSizeLabel.Text = 'Batch Size:';
            app.BatchSizeEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.BatchSizeEditField.Limits = [1 1024];
            app.BatchSizeEditField.RoundFractionalValues = 'on';
            app.BatchSizeEditField.Value = 64;
            app.EpochsLabel = uilabel(app.TrainingParamsPanel);
            app.EpochsLabel.Text = 'Epochs:';
            app.EpochsSpinner = uispinner(app.TrainingParamsPanel);
            app.EpochsSpinner.Limits = [1 500];
            app.EpochsSpinner.RoundFractionalValues = 'on';
            app.EpochsSpinner.Value = 5;
            app.WeightDecayLabel = uilabel(app.TrainingParamsPanel);
            app.WeightDecayLabel.Text = 'Weight Decay (L2):';
            app.WeightDecayEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.WeightDecayEditField.Limits = [0 0.1];
            app.WeightDecayEditField.ValueDisplayFormat = '%.6g';
            app.WeightDecayEditField.Value = 0.0005;
            app.OptimizerLabel = uilabel(app.TrainingParamsPanel);
            app.OptimizerLabel.Text = 'Optimizer:';
            app.OptimizerDropDown = uidropdown(app.TrainingParamsPanel);
            app.OptimizerDropDown.Items = {'sgdm', 'adam', 'rmsprop'};
            app.OptimizerDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizerDropDownValueChanged, true);
            app.OptimizerDropDown.Value = 'sgdm';
            % Create MomentumLabel (sgdm)
            app.MomentumLabel = uilabel(app.TrainingParamsPanel);
            app.MomentumLabel.Text = 'Momentum:';
            app.MomentumEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.MomentumEditField.Limits = [0 0.999];
            app.MomentumEditField.Value = 0.9;
            % Create Beta1Label (adam)
            app.Beta1Label = uilabel(app.TrainingParamsPanel);
            app.Beta1Label.Text = 'Beta1 (gradient decay):';
            app.Beta1EditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.Beta1EditField.Limits = [0 0.9999];
            app.Beta1EditField.Value = 0.9;
            % Create Beta2Label (adam)
            app.Beta2Label = uilabel(app.TrainingParamsPanel);
            app.Beta2Label.Text = 'Beta2 (sq. grad. decay):';
            app.Beta2EditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.Beta2EditField.Limits = [0 0.99999];
            app.Beta2EditField.Value = 0.999;
            % Create RMSDecayLabel (rmsprop)
            app.RMSDecayLabel = uilabel(app.TrainingParamsPanel);
            app.RMSDecayLabel.Text = 'Decay rate:';
            app.RMSDecayEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.RMSDecayEditField.Limits = [0 0.99999];
            app.RMSDecayEditField.Value = 0.9;
            % Create EpsilonLabel (adam, rmsprop; row set by OptimizerDropDownValueChanged)
            app.EpsilonLabel = uilabel(app.TrainingParamsPanel);
            app.EpsilonLabel.Text = 'Epsilon:';
            app.EpsilonEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.EpsilonEditField.Limits = [1e-12 1];
            app.EpsilonEditField.ValueDisplayFormat = '%.1e';
            app.EpsilonEditField.Value = 1e-08;
            %% Validacija i augmentacija
            app.ValidationPanel = uipanel(app.UIFigure);
            app.ValidationPanel.Title = 'Validacija i augmentacija';
            app.ValidationSplitLabel = uilabel(app.ValidationPanel);
            app.ValidationSplitLabel.Text = 'Validacioni skup (%):';
            app.ValidationSplitSpinner = uispinner(app.ValidationPanel);
            app.ValidationSplitSpinner.Limits = [0 50];
            app.ValidationSplitSpinner.RoundFractionalValues = 'on';
            app.ValidationSplitSpinner.Value = 10;
            app.ValidationFrequencyLabel = uilabel(app.ValidationPanel);
            app.ValidationFrequencyLabel.Text = 'Validation Frequency (iter.):';
            app.ValidationFrequencySpinner = uispinner(app.ValidationPanel);
            app.ValidationFrequencySpinner.Limits = [1 10000];
            app.ValidationFrequencySpinner.RoundFractionalValues = 'on';
            app.ValidationFrequencySpinner.Value = 50;
            app.ValidationPatienceLabel = uilabel(app.ValidationPanel);
            app.ValidationPatienceLabel.Text = 'Validation Patience (0 = off):';
            app.ValidationPatienceSpinner = uispinner(app.ValidationPanel);
            app.ValidationPatienceSpinner.Limits = [0 100];
            app.ValidationPatienceSpinner.RoundFractionalValues = 'on';
            app.ValidationPatienceSpinner.Value = 5;
            app.GradientThresholdLabel = uilabel(app.ValidationPanel);
            app.GradientThresholdLabel.Text = 'Gradient Threshold (0 = off):';
            app.GradientThresholdEditField = uieditfield(app.ValidationPanel, 'numeric');
            app.GradientThresholdEditField.Limits = [0 100];
            app.GradientThresholdEditField.Value = 0;
            app.ShuffleLabel = uilabel(app.ValidationPanel);
            app.ShuffleLabel.Text = 'Shuffle:';
            app.ShuffleDropDown = uidropdown(app.ValidationPanel);
            app.ShuffleDropDown.Items = {'every-epoch', 'once', 'never'};
            app.ShuffleDropDown.Value = 'every-epoch';
            app.PlotsLabel = uilabel(app.ValidationPanel);
            app.PlotsLabel.Text = 'Plots:';
            app.PlotsDropDown = uidropdown(app.ValidationPanel);
            app.PlotsDropDown.Items = {'training-progress', 'none'};
            app.PlotsDropDown.Value = 'training-progress';
            app.AugmentationCheckBox = uicheckbox(app.ValidationPanel);
            app.AugmentationCheckBox.Text = 'Augmentacija podataka';
            app.AugmentationCheckBox.Value = false;
            app.AugmentationHintLabel = uilabel(app.ValidationPanel);
            app.AugmentationHintLabel.Text = ['Horizontalno zrcaljenje i mala translacija. ' ...
                'Preporučeno za CIFAR; za MNIST cifre isključiti (zrcaljena cifra nije ista cifra).'];
            %% Rezultati
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Rezultati';
            app.StatusLabel = uilabel(app.ResultsPanel);
            app.StatusLabel.Text = 'Status: Spremno';
            app.TestingAccuracyLabel = uilabel(app.ResultsPanel);
            app.TestingAccuracyLabel.Text = 'Tačnost na test skupu:';
            app.AccuracyLabel = uilabel(app.ResultsPanel);
            app.AccuracyLabel.Text = '—';
            app.ValidationAccuracyLabel = uilabel(app.ResultsPanel);
            app.ValidationAccuracyLabel.Text = 'Tačnost na validacionom skupu:';
            app.ValAccuracyValueLabel = uilabel(app.ResultsPanel);
            app.ValAccuracyValueLabel.Text = '—';
            app.TrainingTimeLabel = uilabel(app.ResultsPanel);
            app.TrainingTimeLabel.Text = 'Vrijeme treniranja:';
            app.TrainingTimeValueLabel = uilabel(app.ResultsPanel);
            app.TrainingTimeValueLabel.Text = '—';
            app.SavedModelLabel = uilabel(app.ResultsPanel);
            app.SavedModelLabel.Text = 'Model:';
            app.SavedModelPathLabel = uilabel(app.ResultsPanel);
            app.SavedModelPathLabel.Text = '';
            app.StartTrainingButton = uibutton(app.ResultsPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Pokreni treniranje';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrainingCNN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end