classdef ColorFormatsConversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreuzmikonvertovanuslikuButton  matlab.ui.control.Button
        YCbCrButton                     matlab.ui.control.Button
        CIELabButton                    matlab.ui.control.Button
        HSLButton                       matlab.ui.control.Button
        RGBButton                       matlab.ui.control.Button
        OdaberikonverzijuLabel          matlab.ui.control.Label
        Converted                       matlab.ui.control.Image
        KonvertovanaslikaLabel          matlab.ui.control.Label
        OriginalnaslikaLabel            matlab.ui.control.Label
        Original                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        LoadedImage % Description
    end
    
    methods (Access = public)
        
        function loadImage(app, image)
        % Display the passed image in a UIAxes
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RGBButton
        function RGBButtonPushed(app, event)
            app.Converted.ImageSource = app.LoadedImage;
        end

        % Button pushed function: HSLButton
        function HSLButtonPushed(app, event)
            hsvImage = rgb2hsv(app.LoadedImage);
            app.Converted.ImageSource = hsvImage;
        end

        % Button pushed function: CIELabButton
        function CIELabButtonPushed(app, event)
            labImage = rgb2lab(app.LoadedImage);
            app.Converted.ImageSource = labImage;
        end

        % Button pushed function: YCbCrButton
        function YCbCrButtonPushed(app, event)
            ycbcrImage = rgb2ycbcr(app.LoadedImage);
            app.Converted.ImageSource = ycbcrImage;
        end

        % Button pushed function: PreuzmikonvertovanuslikuButton
        function PreuzmikonvertovanuslikuButtonPushed(app, event)
            if ~isempty(app.Converted.ImageSource) && isnumeric(app.Converted.ImageSource)
                rgbImage = lab2rgb(app.Converted.ImageSource);
            
                rgbImageUint8 = im2uint8(rgbImage);
            
                [filename, pathname] = uiputfile({'*.png';'*.jpg';'*.tif'}, 'Save Image As');
                if isequal(filename,0)
                    disp('User canceled save.');
                else
                    fullFileName = fullfile(pathname, filename);
                    imwrite(rgbImageUint8, fullFileName);
                    disp(['Image saved to ', fullFileName]);
                end
            else
                uialert(app.UIFigure, 'No image found in Converted.ImageSource.', 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiimage(app.UIFigure);
            app.OriginalnaslikaLabel = uilabel(app.UIFigure);
            app.OriginalnaslikaLabel.Text = 'Originalna slika';
            app.KonvertovanaslikaLabel = uilabel(app.UIFigure);
            app.KonvertovanaslikaLabel.Text = 'Konvertovana slika';
            app.Converted = uiimage(app.UIFigure);
            app.OdaberikonverzijuLabel = uilabel(app.UIFigure);
            app.OdaberikonverzijuLabel.Text = 'Odaberi konverziju';
            app.RGBButton = uibutton(app.UIFigure, 'push');
            app.RGBButton.ButtonPushedFcn = createCallbackFcn(app, @RGBButtonPushed, true);
            app.RGBButton.Text = 'RGB';
            app.HSLButton = uibutton(app.UIFigure, 'push');
            app.HSLButton.ButtonPushedFcn = createCallbackFcn(app, @HSLButtonPushed, true);
            app.HSLButton.Text = 'HSL';
            app.CIELabButton = uibutton(app.UIFigure, 'push');
            app.CIELabButton.ButtonPushedFcn = createCallbackFcn(app, @CIELabButtonPushed, true);
            app.CIELabButton.Text = 'CIE Lab';
            app.YCbCrButton = uibutton(app.UIFigure, 'push');
            app.YCbCrButton.ButtonPushedFcn = createCallbackFcn(app, @YCbCrButtonPushed, true);
            app.YCbCrButton.Text = 'YCbCr';
            app.PreuzmikonvertovanuslikuButton = uibutton(app.UIFigure, 'push');
            app.PreuzmikonvertovanuslikuButton.ButtonPushedFcn = createCallbackFcn(app, @PreuzmikonvertovanuslikuButtonPushed, true);
            app.PreuzmikonvertovanuslikuButton.Text = 'Preuzmi konvertovanu sliku';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorFormatsConversion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end