classdef Filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TipumaLabel                   matlab.ui.control.Label
        GaussianButton                matlab.ui.control.Button
        PrimeninekiodfilteraLabel     matlab.ui.control.Label
        SlikasaprimenjenimfilteromLabel  matlab.ui.control.Label
        FilteredImage                 matlab.ui.control.Image
        DFilteringBM3DButton          matlab.ui.control.Button
        BlockMatchingalgoritamButton  matlab.ui.control.Button
        GaussianfilterButton          matlab.ui.control.Button
        MedianfilterButton            matlab.ui.control.Button
        SlikasaumomLabel              matlab.ui.control.Label
        NoisyImage                    matlab.ui.control.Image
        OriginalnaslikaLabel          matlab.ui.control.Label
        SaltPepperButton              matlab.ui.control.Button
        OdaberitejainuumaSlider       matlab.ui.control.Slider
        OdaberitejainuumaSliderLabel  matlab.ui.control.Label
        Original                      matlab.ui.control.Image
    end

   properties (Access = private)
        LoadedImage % Description
        noiseValue % Description
    end
    
    methods (Access = public)
        
        function loadImage(app, image)
        % Display the passed image in a UIAxes
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: OdaberitejainuumaSlider
        function OdaberitejainuumaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.noiseValue = event.Value;
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            % Učitaj originalnu sliku
            originalImage = app.LoadedImage;
           % Dobij vrednost jačine šuma sa slajdera
            noiseLevel = app.OdaberitejainuumaSlider.Value;  % Vrednost sa slajdera
            noiseLevel = noiseLevel / 10;
            % Dodaj Salt & Pepper šum na sliku
            noisyImage = imnoise(originalImage, 'salt & pepper', noiseLevel);
            % Prikazivanje šumovite slike u aplikaciji
            app.NoisyImage.ImageSource = noisyImage;
        end

        % Button pushed function: MedianfilterButton
        function MedianfilterButtonPushed(app, event)
           noisyImage = app.NoisyImage.ImageSource;
            % Proveri da li je slika u boji (RGB)
            if size(noisyImage, 3) == 3
                % Razdvoj RGB kanale
                redChannel = noisyImage(:, :, 1);
                greenChannel = noisyImage(:, :, 2);
                blueChannel = noisyImage(:, :, 3);
                % Primeni median filter na svaki kanal
                filteredRed = medfilt2(redChannel, [3 3]);
                filteredGreen = medfilt2(greenChannel, [3 3]);
                filteredBlue = medfilt2(blueChannel, [3 3]);
                % Kombinuj filtrirane kanale nazad u RGB sliku
                filteredImage = cat(3, filteredRed, filteredGreen, filteredBlue);
            else
                % Ako je slika u nijansama sive, direktno primeni median filter
                filteredImage = medfilt2(noisyImage, [3 3]);
            end
            % Prikazivanje filtrirane slike u aplikaciji
            app.FilteredImage.ImageSource = filteredImage;
        end

        % Button pushed function: GaussianfilterButton
        function GaussianfilterButtonPushed(app, event)
            % Učitaj šumovitu sliku
            noisyImage = app.NoisyImage.ImageSource;
    
            % Definiši Gaussian kernel
            gaussianKernelSize = 3; % Veličina kernela (npr. 3x3)
            gaussianSigma = 0.5;    % Standardna devijacija za Gaussian filter
            gaussianKernel = fspecial('gaussian', gaussianKernelSize, gaussianSigma);


            if size(noisyImage, 3) == 3
                % Primena Gaussian filtera na svaki kanal RGB slike
                filteredR = imgaussfilt(noisyImage(:, :, 1), 2); % Crveni kanal
                filteredG = imgaussfilt(noisyImage(:, :, 2), 2); % Zeleni kanal
                filteredB = imgaussfilt(noisyImage(:, :, 3), 2); % Plavi kanal
            end
                % Kombinovanje kanala nazad u RGB sliku
                filteredImage = cat(3, filteredR, filteredG, filteredB);
        
            % Prikazivanje filtrirane slike u aplikaciji
            app.FilteredImage.ImageSource = filteredImage;
        end

        % Button pushed function: BlockMatchingalgoritamButton
        function BlockMatchingalgoritamButtonPushed(app, event)
           % Proverite da li su slike učitane
            if ~isempty(app.Original.ImageSource) && ~isempty(app.NoisyImage.ImageSource)
            % Učitajte slike
            originalImage = app.Original.ImageSource; % Originalna slika (RGB)
            noisyImage = app.NoisyImage.ImageSource; % Šumna slika (RGB)
            
            % Proverite da li su slike RGB
            if size(originalImage, 3) == 3 && size(noisyImage, 3) == 3
                % Parametri algoritma
                blockSize = 16; % Veličina bloka (npr. 16x16)
                searchRange = 8; % Opseg pretrage (npr. ±8 piksela)
            
                % Kreiranje prazne matrice za filtriranu sliku
                filteredImage = zeros(size(noisyImage), 'like', noisyImage);
            
                % Iteracija kroz svaki kanal (R, G, B)
                for channel = 1:3
                    % Uzmite odgovarajući kanal iz RGB slika
                    originalChannel = originalImage(:, :, channel);
                    noisyChannel = noisyImage(:, :, channel);
            
                    % Dimenzije slike
                    [height, width] = size(originalChannel);
            
                    % Iteracija kroz blokove slike
                    for i = 1:blockSize:height-blockSize+1
                        for j = 1:blockSize:width-blockSize+1
                            % Izdvajanje bloka iz originalnog kanala
                            refBlock = originalChannel(i:i+blockSize-1, j:j+blockSize-1);
            
                            % Inicijalizacija minimalnog SAD i pozicije
                            minSAD = inf;
                            bestMatch = [0, 0];
            
                            % Pretraga u okviru opsega
                            for m = -searchRange:searchRange
                                for n = -searchRange:searchRange
                                    % Koordinate u šumnoj slici
                                    currRow = i + m;
                                    currCol = j + n;
            
                                    % Provera da li je blok unutar granica slike
                                    if currRow > 0 && currRow+blockSize-1 <= height && ...
                                       currCol > 0 && currCol+blockSize-1 <= width
                                        % Izdvajanje bloka iz šumnog kanala
                                        noisyBlock = noisyChannel(currRow:currRow+blockSize-1, currCol:currCol+blockSize-1);
            
                                        % Izračunavanje SAD
                                        SAD = sum(abs(refBlock(:) - noisyBlock(:)));
            
                                        % Ažuriranje najboljeg meča
                                        if SAD < minSAD
                                            minSAD = SAD;
                                            bestMatch = [m, n];
                                        end
                                    end
                                end
                            end
            
                            % Kopiranje najboljeg bloka u filtriranu sliku
                            bestRow = i + bestMatch(1);
                            bestCol = j + bestMatch(2);
                            filteredImage(i:i+blockSize-1, j:j+blockSize-1, channel) = ...
                                noisyChannel(bestRow:bestRow+blockSize-1, bestCol:bestCol+blockSize-1);
                        end
                    end
                end
            
                % Prikaz filtrirane slike u RGB formatu
                app.FilteredImage.ImageSource = filteredImage;
            
            else
                % Ako slike nisu RGB, prijavite grešku
                uialert(app.UIFigure, 'Jedna ili obe slike nisu RGB!', 'Greška');
            end
            else
            % Ako slike nisu učitane, prijavite grešku
            uialert(app.UIFigure, 'Obe slike moraju biti učitane!', 'Greška');
            end
        end

        % Button pushed function: DFilteringBM3DButton
        function DFilteringBM3DButtonPushed(app, event)
         noisyImage = app.NoisyImage.ImageSource;   % RGB image with noise
            originalImage = app.Original.ImageSource;  % Optional, for PSNR
            
            % If image is uint8, normalize to [0,1]
            if ~isfloat(noisyImage)
                noisyImageNorm = im2double(noisyImage);
            else
                % Assume already [0,1]
                noisyImageNorm = noisyImage;
            end
            
            % Noise standard deviation (adjust for your noise level)
            % Example: for sigma = 25 in [0,255] range -> 25/255 in [0,1]
            sigma = 25/255;
            
            % Preallocate filtered image
            filteredImage = zeros(size(noisyImageNorm));
            
            % Process each channel separately with BM3D
            for channel = 1:size(noisyImageNorm, 3)
                [~, denoised] = BM3D(1, noisyImageNorm(:,:,channel), sigma);
                filteredImage(:,:,channel) = denoised;
            end
            
            % Clip in case BM3D produces slight <0 or >1 values
            filteredImage = min(max(filteredImage,0),1);
            
            % Convert back to uint8 for display
            filteredImageUint8 = im2uint8(filteredImage);
            
            % Assign to app image control
            app.FilteredImage.ImageSource = filteredImageUint8;
            
            % Optional PSNR calculation
            if ~isempty(originalImage)
                % Normalize original for comparison
                if ~isfloat(originalImage)
                    originalImageNorm = im2double(originalImage);
                else
                    originalImageNorm = originalImage;
                end
                
                psnrValue = psnr(filteredImage, originalImageNorm);
                disp(['PSNR: ', num2str(psnrValue), ' dB']);
            end
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            originalImage = app.LoadedImage;

            sliderValue = app.OdaberitejainuumaSlider.Value;

            
            sigma = sliderValue * 5;
            if ~isfloat(originalImage)
                origDouble = im2double(originalImage);
            else
                origDouble = originalImage;
            end
        
            variance = (sigma/255)^2;
        
            noisyImage = imnoise(origDouble, 'gaussian', 0, variance);
        
            app.NoisyImage.ImageSource = im2uint8(noisyImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiimage(app.UIFigure);
            app.OdaberitejainuumaSliderLabel = uilabel(app.UIFigure);
            app.OdaberitejainuumaSliderLabel.Text = 'Odaberite jačinu šuma';
            app.OdaberitejainuumaSlider = uislider(app.UIFigure);
            app.OdaberitejainuumaSlider.Limits = [0 10];
            app.OdaberitejainuumaSlider.ValueChangingFcn = createCallbackFcn(app, @OdaberitejainuumaSliderValueChanging, true);
            app.SaltPepperButton = uibutton(app.UIFigure, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.OriginalnaslikaLabel = uilabel(app.UIFigure);
            app.OriginalnaslikaLabel.Text = 'Originalna slika';
            app.NoisyImage = uiimage(app.UIFigure);
            app.SlikasaumomLabel = uilabel(app.UIFigure);
            app.SlikasaumomLabel.Text = 'Slika sa šumom';
            app.MedianfilterButton = uibutton(app.UIFigure, 'push');
            app.MedianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianfilterButtonPushed, true);
            app.MedianfilterButton.Text = 'Median filter';
            app.GaussianfilterButton = uibutton(app.UIFigure, 'push');
            app.GaussianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianfilterButtonPushed, true);
            app.GaussianfilterButton.Text = 'Gaussian filter';
            app.BlockMatchingalgoritamButton = uibutton(app.UIFigure, 'push');
            app.BlockMatchingalgoritamButton.ButtonPushedFcn = createCallbackFcn(app, @BlockMatchingalgoritamButtonPushed, true);
            app.BlockMatchingalgoritamButton.Text = 'Block-Matching algoritam';
            app.DFilteringBM3DButton = uibutton(app.UIFigure, 'push');
            app.DFilteringBM3DButton.ButtonPushedFcn = createCallbackFcn(app, @DFilteringBM3DButtonPushed, true);
            app.DFilteringBM3DButton.Text = '3D Filtering (BM3D)';
            app.FilteredImage = uiimage(app.UIFigure);
            app.SlikasaprimenjenimfilteromLabel = uilabel(app.UIFigure);
            app.SlikasaprimenjenimfilteromLabel.Text = 'Slika sa primenjenim filterom';
            app.PrimeninekiodfilteraLabel = uilabel(app.UIFigure);
            app.PrimeninekiodfilteraLabel.Text = 'Primeni neki od filtera';
            app.GaussianButton = uibutton(app.UIFigure, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.TipumaLabel = uilabel(app.UIFigure);
            app.TipumaLabel.Text = 'Tip šuma:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end