classdef HarrisCornerDetectionFeature < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Threshold0Label               matlab.ui.control.Label
        pokretanjeHarrisalgoritmaButton  matlab.ui.control.Button
        Sliderzapodeavanjepraga       matlab.ui.control.Slider
        SliderzapodeavanjepragaLabel  matlab.ui.control.Label
        Original                      matlab.ui.control.Image
        HARRISCORNERDETECTIONLabel    matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LoadedImage % Description
        sliderValue = 0 % Description
    end

    methods (Access = public)
        
        function loadImage(app, image)
        % Display the passed image in a UIAxes
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Sliderzapodeavanjepraga
        function SliderzapodeavanjepragaValueChanged(app, event)
            %app.sliderValue = app.Sliderzapodeavanjepraga.Value;
            %app.Threshold0Label.Text = sprintf('Threshold: %.2f', app.sliderValue);
        end

        % Button pushed function: pokretanjeHarrisalgoritmaButton
        function pokretanjeHarrisalgoritmaButtonPushed(app, event)
            if size(app.LoadedImage, 3) == 3
                grayImage = im2gray(app.LoadedImage);
            else
                grayImage = app.LoadedImage;
            end
            
            [rows, cols] = size(grayImage);
            grayImage = im2double(grayImage);
        
            [Ix, Iy] = imgradientxy(grayImage);
        
            Ix2 = imgaussfilt(Ix.^2, 1);
            Iy2 = imgaussfilt(Iy.^2, 1);
            Ixy = imgaussfilt(Ix .* Iy, 1);
        
            k = 0.04;
            R = (Ix2 .* Iy2 - Ixy.^2) - k * (Ix2 + Iy2).^2;
        
            Rmax = max(R(:));
            corners = R > app.sliderValue * Rmax;
        
            [cornerRows, cornerCols] = find(corners);

            imshow(app.LoadedImage, 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, cornerCols, cornerRows, 'ro', 'MarkerSize', 5, 'LineWidth', 1.5);
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Detected Corners');
        end

        % Value changing function: Sliderzapodeavanjepraga
        function SliderzapodeavanjepragaValueChanging(app, event)
            app.sliderValue = event.Value;
            app.Threshold0Label.Text = sprintf('Threshold: %.2f', app.sliderValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Prikaz slike i rezultata.')
            app.HARRISCORNERDETECTIONLabel = uilabel(app.UIFigure);
            app.HARRISCORNERDETECTIONLabel.Text = 'HARRIS CORNER DETECTION';
            app.Original = uiimage(app.UIFigure);
            app.SliderzapodeavanjepragaLabel = uilabel(app.UIFigure);
            app.SliderzapodeavanjepragaLabel.Text = 'Slider za podešavanje praga.';
            app.Sliderzapodeavanjepraga = uislider(app.UIFigure);
            app.Sliderzapodeavanjepraga.Limits = [0 1];
            app.Sliderzapodeavanjepraga.ValueChangedFcn = createCallbackFcn(app, @SliderzapodeavanjepragaValueChanged, true);
            app.Sliderzapodeavanjepraga.ValueChangingFcn = createCallbackFcn(app, @SliderzapodeavanjepragaValueChanging, true);
            app.pokretanjeHarrisalgoritmaButton = uibutton(app.UIFigure, 'push');
            app.pokretanjeHarrisalgoritmaButton.ButtonPushedFcn = createCallbackFcn(app, @pokretanjeHarrisalgoritmaButtonPushed, true);
            app.pokretanjeHarrisalgoritmaButton.Text = 'pokretanje Harris algoritma.';
            app.Threshold0Label = uilabel(app.UIFigure);
            app.Threshold0Label.Text = 'Threshold: 0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HarrisCornerDetectionFeature

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end