classdef ImageFusion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PreuzmislikuButton     matlab.ui.control.Button
        KombinovanaslikaLabel  matlab.ui.control.Label
        DrugaslikaLabel        matlab.ui.control.Label
        PrvaslikaLabel         matlab.ui.control.Label
        KombinujslikeButton    matlab.ui.control.Button
        UitajdruguslikuButton  matlab.ui.control.Button
        UitajprvuslikuButton   matlab.ui.control.Button
        FusedImage             matlab.ui.control.Image
        SecondImage            matlab.ui.control.Image
        FirstImage             matlab.ui.control.Image
    end

    
    properties (Access = private)
        Image1 % Description
        Image2 % Description
        im1Loaded = false; % Description
        im2Loaded = false; % Description
        DownloadImge; % Description
    end
    
    methods (Access = public)
        
        function results = loadImage1(app)
            [filename, pathname] = uigetfile('*.png;*.jpg;*.jpeg', "Izaberi sliku");

            if isequal(filename, 0)
                figure(app.UIFigure);
                return;
            end

            filename = strcat(pathname, filename);
            app.Image1=imread(filename);
            app.FirstImage.ImageSource = app.Image1;
            app.im1Loaded = true;

            figure(app.UIFigure);
        end
        
        function results = loadImage2(app)
            [filename, pathname] = uigetfile('*.png;*.jpg;*.jpeg', "Izaberi sliku");

            if isequal(filename, 0)
                figure(app.UIFigure);
                return;
            end

            filename = strcat(pathname, filename);
            app.Image2=imread(filename);
            app.SecondImage.ImageSource = app.Image2;
            app.im2Loaded = true;

            figure(app.UIFigure);
        end
        
        function results = fuseImages(app)
            if isempty(app.Image1) || isempty(app.Image2)
                uialert(app.UIFigure, 'Please load both images before fusing.', 'Error');
                return;
            end
        
            % Ensure images are RGB
            if size(app.Image1, 3) ~= 3
                uialert(app.UIFigure, 'Image 1 must be a color image.', 'Error');
                return;
            end
            if size(app.Image2, 3) ~= 3
                uialert(app.UIFigure, 'Image 2 must be a color image.', 'Error');
                return;
            end
        
            % Resize images to the same size
            minRows = min(size(app.Image1, 1), size(app.Image2, 1));
            minCols = min(size(app.Image1, 2), size(app.Image2, 2));
            
            img1 = im2double(imresize(app.Image1, [minRows, minCols]));
            img2 = im2double(imresize(app.Image2, [minRows, minCols]));
        
            % Initialize the fused image
            fusedImage = zeros(size(img1));
        
            % Process each color channel separately
            for channel = 1:3
                % Perform DWT on both images for the current channel
                [cA1, cH1, cV1, cD1] = dwt2(img1(:, :, channel), 'haar');
                [cA2, cH2, cV2, cD2] = dwt2(img2(:, :, channel), 'haar');
        
                % Fuse coefficients
                fusedCA = min(cA1, cA2) % Average approximation coefficients
                fusedCH = max(cH1, cH2);   % Maximum detail coefficients
                fusedCV = max(cV1, cV2);
                fusedCD = max(cD1, cD2);
        
                % Perform Inverse DWT
                fusedChannel = idwt2(fusedCA, fusedCH, fusedCV, fusedCD, 'haar');

                % Clamp values to [0, 1]
                fusedChannel = min(max(fusedChannel, 0), 1);
        
                % Store the fused channel
                fusedImage(:, :, channel) = fusedChannel;
            end
        
            % Convert the fused image to uint8
            fusedImage = im2uint8(fusedImage);
        
            % Display the fused image in the Image component
            app.DownloadImge = fusedImage;
            app.FusedImage.ImageSource = fusedImage;
        end
    end
    
    methods (Access = public)
        
        function results = DownloadFusedImage(app)
             if isempty(app.DownloadImge)
                uialert(app.UIFigure, 'Ne postoji kombinovana slika za preuzimanje. Kombinujte slike prvo', 'Error');
                return;
            end
        
            [file, path] = uiputfile({'*.jpg', 'JPEG Image (*.jpg)'; '*.png', 'PNG Image (*.png)'}, 'Save Fused Image');
            if isequal(file, 0)
                return; % User canceled
            end
        
            % Save the fused image
            imwrite(app.DownloadImge, fullfile(path, file));
            uialert(app.UIFigure, 'Kombinovana slika je uspešno sačuvana!', 'Success');
            figure(app.UIFigure);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UitajprvuslikuButton
        function UitajprvuslikuButtonPushed(app, event)
            loadImage1(app);
        end

        % Button pushed function: UitajdruguslikuButton
        function UitajdruguslikuButtonPushed(app, event)
            loadImage2(app);
        end

        % Button pushed function: KombinujslikeButton
        function KombinujslikeButtonPushed(app, event)
            fuseImages(app);
        end

        % Button pushed function: PreuzmislikuButton
        function PreuzmislikuButtonPushed(app, event)
            DownloadFusedImage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FirstImage = uiimage(app.UIFigure);
            app.SecondImage = uiimage(app.UIFigure);
            app.FusedImage = uiimage(app.UIFigure);
            app.UitajprvuslikuButton = uibutton(app.UIFigure, 'push');
            app.UitajprvuslikuButton.ButtonPushedFcn = createCallbackFcn(app, @UitajprvuslikuButtonPushed, true);
            app.UitajprvuslikuButton.Text = 'Učitaj prvu sliku';
            app.UitajdruguslikuButton = uibutton(app.UIFigure, 'push');
            app.UitajdruguslikuButton.ButtonPushedFcn = createCallbackFcn(app, @UitajdruguslikuButtonPushed, true);
            app.UitajdruguslikuButton.Text = 'Učitaj drugu sliku';
            app.KombinujslikeButton = uibutton(app.UIFigure, 'push');
            app.KombinujslikeButton.ButtonPushedFcn = createCallbackFcn(app, @KombinujslikeButtonPushed, true);
            app.KombinujslikeButton.Text = 'Kombinuj slike';
            app.PrvaslikaLabel = uilabel(app.UIFigure);
            app.PrvaslikaLabel.Text = 'Prva slika';
            app.DrugaslikaLabel = uilabel(app.UIFigure);
            app.DrugaslikaLabel.Text = 'Druga slika';
            app.KombinovanaslikaLabel = uilabel(app.UIFigure);
            app.KombinovanaslikaLabel.Text = 'Kombinovana slika';
            app.PreuzmislikuButton = uibutton(app.UIFigure, 'push');
            app.PreuzmislikuButton.ButtonPushedFcn = createCallbackFcn(app, @PreuzmislikuButtonPushed, true);
            app.PreuzmislikuButton.Text = 'Preuzmi sliku';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFusion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end