classdef WienerFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TitleLabel           matlab.ui.control.Label
        ControlsPanel        matlab.ui.container.Panel
        SectionLabel         matlab.ui.control.Label
        PSFEditFieldLabel    matlab.ui.control.Label
        PSFEditField         matlab.ui.control.EditField
        SNREditField_2Label  matlab.ui.control.Label
        SNREditField         matlab.ui.control.NumericEditField
        ApplyFilterButton    matlab.ui.control.Button
        OriginalPanel        matlab.ui.container.Panel
        Original             matlab.ui.control.Image
        FilteredPanel        matlab.ui.container.Panel
        FilteredImage        matlab.ui.control.Image
    end

    properties (Access = private)
        LoadedImage % Slika prosleđena iz Options prozora
    end

    methods (Access = public)

        function loadImage(app, image)
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            if isempty(app.LoadedImage)
                uialert(app.UIFigure, 'Morate izabrati sliku prvo!', 'Warning');
                return;
            end

            % Dohvati učitanu sliku
            originalImage = app.LoadedImage;

            % Proveri da li je slika grayscale (ako nije, konvertuj je)
            if size(originalImage, 3) == 3
                grayImage = rgb2gray(originalImage);
            else
                grayImage = originalImage;
            end

            % Parsiraj PSF vrednost iz unosa kao tekst
            psfText = app.PSFEditField.Value;  % Tekstualni unos PSF-a
            psfMatrix = str2num(psfText);  % Parsiranje teksta u matricu

            % Proveri da li je PSF ispravno unet
            if isempty(psfMatrix) || ~ismatrix(psfMatrix) || size(psfMatrix, 1) ~= 3 || size(psfMatrix, 2) ~= 3
                uialert(app.UIFigure, 'PSF format nije ispravan. Unesite matricu u formatu: [0 1 0; 1 1 1; 0 1 0]', 'Greška');
                return;
            end

            % Prikazivanje PSF matrice u komandnom prozoru radi provere
            disp('PSF Matriza:');
            disp(psfMatrix);

            % Proveri da li je SNR unet
            snrValue = app.SNREditField.Value;  % Unos za SNR
            if isempty(snrValue) || snrValue <= 0
                uialert(app.UIFigure, 'Molimo unesite validan SNR.', 'Greška');
                return;
            end

            % Primeni dekonvoluciju sa Wiener filterom koristeći PSF i SNR
            deconvolvedImage = deconvwnr(grayImage, psfMatrix, snrValue);

            % Normalizuj dekonvoluciju na opseg [0, 255]
            deconvolvedImage = mat2gray(deconvolvedImage);  % Normalizuje sliku na [0, 1]
            deconvolvedImage = uint8(deconvolvedImage * 255);  % Skalira vrednosti na [0, 255]

            % Proveri da li je slika pravilno generisana
            if all(deconvolvedImage(:) == 0)
                uialert(app.UIFigure, 'Dekonvolucija nije dala validan rezultat. Proverite vrednosti PSF i SNR.', 'Greška');
                return;
            end

            % Prikaz filtrirane slike (uiimage traži 3-kanalnu sliku)
            app.FilteredImage.ImageSource = repmat(deconvolvedImage, [1 1 3]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Wiener filter';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Wiener filter';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Parametri';
            app.SectionLabel = uilabel(app.ControlsPanel);
            app.SectionLabel.Text = 'Wiener dekonvolucija (deconvwnr)';
            app.PSFEditFieldLabel = uilabel(app.ControlsPanel);
            app.PSFEditFieldLabel.Text = 'PSF (matrica 3x3):';
            app.PSFEditField = uieditfield(app.ControlsPanel, 'text');
            app.PSFEditField.Value = '0 1 0; 1 1 1; 0 1 0';
            app.SNREditField_2Label = uilabel(app.ControlsPanel);
            app.SNREditField_2Label.Text = 'SNR:';
            app.SNREditField = uieditfield(app.ControlsPanel, 'numeric');
            app.SNREditField.Value = 0.01;
            app.ApplyFilterButton = uibutton(app.ControlsPanel, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Primeni filter';
            app.OriginalPanel = uipanel(app.UIFigure);
            app.OriginalPanel.Title = 'Originalna slika';
            app.Original = uiimage(app.OriginalPanel);
            app.FilteredPanel = uipanel(app.UIFigure);
            app.FilteredPanel.Title = 'Filtrirana slika';
            app.FilteredImage = uiimage(app.FilteredPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WienerFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end